classdef RMP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        EditField       matlab.ui.control.NumericEditField
        RightACCButton  matlab.ui.control.Button
        EditField_2     matlab.ui.control.NumericEditField
        LeftACCButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        tree; %data
        data;
       
        jLeftHip;
        jRightHip;
        fileComments;
        frameRate;
        jRightKnee;
        jLeftKnee;
        nSamples;
        time;
        mvnxVersion;
        suitLabel;
        originialFilename;
        recDate;
        segmentCount;
        sensorData;
        segmentData;
        Pelvis;
        L5;
        L3;
        T12;
        T8;
        Neck;
        Head;
        RightShoulder;
        RightUA;
        RightFA;
        RightH;
        LeftShoulder;
        LeftUA;
        LeftFA;
        LeftH;
        RightUL;
        RightLL;
        RightF;
        RightToe;
        LeftUL;
        LeftLL;
        LeftF;
        LeftToe;
        jT9T8;
        jT1C7;
        jRightWrist;
        jRightT4Shoulder;
        jRightShoulder;
        jRightElbow;
        jRightBallFoot;
        jRightAnkle;
        jLeftWrist;
        jLeftT4Shoulder;
        jLeftShoulder;
        jLeftElbow;
        jLeftBallFoot;
        jLeftAnkle;
        jL5S1;
        jL4L3;
        jL1T12;
        jC1Head;
        
        Time_bin;
        Time_bin_L;
        HL;
        HL_L;
        KL;
        KL_L;
        acost;
        acost_L;
        asint;
        asint_L;
        Pelvis_x;
        Pelvis_x_L;
        Right_foot_x;
        Left_foot_x;
        Right_foot_z;
        Left_foot_z;
        Right_T;
        Left_T;
        Right_HS;
        Left_HS;
        Right_HS_Time;
        Left_HS_Time;
        index;
        index_L;
        Right_HS_Index;
        Left_HS_Index;
        R_middle_25;
        L_middle_25;
        R_middle_50;
        L_middle_50;
        Right_HS_Index_mid;
        Left_HS_Index_mid;
        Right_period;
        Left_period;
        period_mean;
        Right_Hipbin;
        Left_Hipbin;
        Right_Kneebin;
        Left_Kneebin;
        FRM; 
        FRM_2;
        Right_Hip_norm;
        Left_Hip_norm;
        Right_Knee_norm;
        Left_Knee_norm;
        
    end
    
    methods (Access = private)
        
        function Load_Plot(app)  
            app.tree = load_mvnx('P18_T1_L_D1');
            app.mvnxVersion = app.tree.metaData.mvnx_version; 

                if (isfield(app.tree.metaData, 'comment'))
                    app.fileComments = app.tree.metaData.comment; 
                end
                
     %% Read some basic properties of the subject;
            app.frameRate = app.tree.metaData.subject_frameRate;
            app.suitLabel = app.tree.metaData.subject_label;
            %app.originalFilename = app.tree.metaData.subject_originalFilename;
            app.recDate = app.tree.metaData.subject_recDate;
            app.segmentCount = app.tree.metaData.subject_segmentCount;
            
     %% Retrieve sensor labels
     % %creates a struct with sensor data
                if isfield(app.tree,'sensorData') && isstruct(app.tree.sensorData)
                    app.sensorData = app.tree.sensorData;
                end
     %% Retrieve segment labels
     % %creates a struct with segment definitions
                if isfield(app.tree,'segmentData') && isstruct(app.tree.segmentData)
                    app.segmentData = app.tree.segmentData;
                end

     %retrieve the data frames from the subject
            app.nSamples = length(app.tree.segmentData);
            
            app.time = str2double({app.tree.frame.time})/1000;
      
     % Segment Data
        app.Pelvis = app.tree.segmentData(1).position;    %X,Y,Z  
    
        app.L5 = app.tree.segmentData(2).position;    %X,Y,Z  
        
        app.L3 = app.tree.segmentData(3).position;    %X,Y,Z  
        
        app.T12 = app.tree.segmentData(4).position;    %X,Y,Z  
       
        app.T8 = app.tree.segmentData(5).position;    %X,Y,Z  
    
        app.Neck = app.tree.segmentData(6).position;    %X,Y,Z  
    
        app.Head = app.tree.segmentData(7).position;    %X,Y,Z  
    
        app.RightShoulder = app.tree.segmentData(8).position;    %X,Y,Z  
    
        app.RightUA = app.tree.segmentData(9).position;    %X,Y,Z  
    
        app.RightFA = app.tree.segmentData(10).position;    %X,Y,Z 
     
        app.RightH = app.tree.segmentData(11).position;    %X,Y,Z  
 
        app.LeftShoulder = app.tree.segmentData(12).position;    %X,Y,Z  
     
        app.LeftUA = app.tree.segmentData(13).position;    %X,Y,Z 
 
        app.LeftFA = app.tree.segmentData(14).position;    %X,Y,Z  
 
        app.LeftH = app.tree.segmentData(15).position;    %X,Y,Z  
 
        app.RightUL = app.tree.segmentData(16).position;    %X,Y,Z  
   
        app.RightLL = app.tree.segmentData(17).position;    %X,Y,Z  
   
        app.RightF = app.tree.segmentData(18).position;    %X,Y,Z  
 
        app.RightToe = app.tree.segmentData(19).position;    %X,Y,Z 
  
        app.LeftUL = app.tree.segmentData(20).position;    %X,Y,Z  

        app.LeftLL = app.tree.segmentData(21).position;    %X,Y,Z  
   
        app.LeftF = app.tree.segmentData(22).position;    %X,Y,Z  
  
        app.LeftToe = app.tree.segmentData(23).position;    %X,Y,Z 
        
     % Joint Data       
            
        app.jL5S1 = app.tree.jointData(1).jointAngle;    %X,Y,Z  
 
        app.jL4L3 = app.tree.jointData(2).jointAngle;    %X,Y,Z
        
        app.jL1T12 = app.tree.jointData(3).jointAngle;    %X,Y,Z
       
        app.jT9T8 = app.tree.jointData(4).jointAngle;    %X,Y,Z 
       
        app.jT1C7 = app.tree.jointData(5).jointAngle;    %X,Y,Z 
      
        app.jC1Head = app.tree.jointData(6).jointAngle;    %X,Y,Z
     
        app.jRightT4Shoulder = app.tree.jointData(7).jointAngle;    %X,Y,Z 
     
        app.jRightShoulder = app.tree.jointData(8).jointAngle;    %X,Y,Z
      
        app.jRightElbow = app.tree.jointData(9).jointAngle;    %X,Y,Z 
       
        app.jRightWrist = app.tree.jointData(10).jointAngle;    %X,Y,Z 
      
        app.jLeftT4Shoulder = app.tree.jointData(11).jointAngle;    %X,Y,Z 
        
        app.jLeftShoulder = app.tree.jointData(12).jointAngle;    %X,Y,Z 
        
        app.jLeftElbow = app.tree.jointData(13).jointAngle;    %X,Y,Z 
        
        app.jLeftWrist = app.tree.jointData(14).jointAngle;    %X,Y,Z 
        
        app.jRightHip = app.tree.jointData(15).jointAngle;    %X,Y,Z 
        
        app.jRightKnee = app.tree.jointData(16).jointAngle;    %X,Y,Z 
        
        app.jRightAnkle = app.tree.jointData(17).jointAngle;    %X,Y,Z 
        
        app.jRightBallFoot = app.tree.jointData(18).jointAngle;    %X,Y,Z 
        
        app.jLeftHip = app.tree.jointData(19).jointAngle;    %X,Y,Z 
        
        app.jLeftKnee = app.tree.jointData(20).jointAngle;    %X,Y,Z
       
        app.jLeftAnkle = app.tree.jointData(21).jointAngle;    %X,Y,Z
        
        app.jLeftBallFoot = app.tree.jointData(22).jointAngle;    %X,Y,Z
     
     
     %plotting
            plot(app.UIAxes,app.jLeftAnkle(:,3));%jLeftHip(:,1));
            hold(app.UIAxes)
            plot(app.UIAxes,app.jRightAnkle(:,3));%jRightHip(:,1));
            legend(app.UIAxes,'Left Hip','Right Hip','Location','bestoutside')
            
            plot(app.UIAxes_2,app.jLeftKnee(:,2));
            hold(app.UIAxes_2)
            plot(app.UIAxes_2,app.jRightKnee(:,2));
            legend(app.UIAxes_2,'Left Knee','Right Knee','Location','bestoutside');
                                               
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Load_Plot(app)
            
        end

        % Button pushed function: RightACCButton
        function RightACCButtonPushed(app, event)

        app.Time_bin = [];
        app.HL = [];
        app.KL = [];
        app.acost = [];
        app.asint = [];
        
        %% Right
        
        app.Pelvis_x = app.Pelvis(:,1);                                      
        app.Right_foot_x = app.RightF(:,1);                                               
        app.Right_foot_z = app.RightF(:,3);                                               
        app.Right_T = app.Right_foot_x-app.Pelvis_x;

        app.Right_HS = islocalmax(app.Right_T,'MinProminence',.1);                           
        app.Right_HS_Time = app.time(app.Right_HS);                                            
        app.index = 1:length(app.time);
        app.Right_HS_Index = app.index(app.Right_HS);                                          
        app.R_middle_25 = ceil(length(app.Right_HS_Index)*.25);                               
        app.R_middle_50 = ceil(length(app.Right_HS_Index)*.75);                              
        app.Right_HS_Index_mid = app.Right_HS_Index(app.R_middle_25:app.R_middle_50);
                
        for i=1:length(app.Right_HS_Index_mid)-1                                           
            app.Right_period(i) = app.time((app.Right_HS_Index_mid(i+1)-1))-app.time((app.Right_HS_Index_mid(i)));
        end

        app.period_mean = mean(app.Right_period);                                          

        for i=1:length(app.Right_HS_Index_mid)-1                                           
            app.Time_bin{i} = app.time(app.Right_HS_Index_mid(i):(app.Right_HS_Index_mid(i+1)-1));
        end
                                                        
        app.jRightHip = app.jRightHip(:,end);                                              

        for i=1:length(app.Right_HS_Index_mid)-1                                           
            app.Right_Hipbin{i} = app.jRightHip(app.Right_HS_Index_mid(i):(app.Right_HS_Index_mid(i+1)-1));
        end

        app.jRightKnee = app.jRightKnee(:,end);

        for i=1:length(app.Right_HS_Index_mid)-1                                           
            app.Right_Kneebin{i} = app.jRightKnee(app.Right_HS_Index_mid(i):app.Right_HS_Index_mid(i+1)-1);
        end

        app.FRM = round(mean(cellfun(@length,app.Right_Hipbin)));             

        for i =1:length(app.Time_bin)
            x = app.Time_bin{i}';
            T1 = x(1);
            T2 = x(end);
            T3 = T2 - T1;
            xq = T1:(T3/(app.FRM-1)):T2;
            v1 = app.Right_Hipbin{i}';
            v2 = app.Right_Kneebin{i}';
    
            app.Right_Hip_norm(:,i) = interp1(x,v1,xq,'spline')';
            app.Right_Knee_norm(:,i) = interp1(x,v2,xq,'spline')';

        end

        for i=1:app.FRM-1
   
            app.HL(i,:) = app.Right_Hip_norm(i+1,:) - app.Right_Hip_norm(i,:);
            app.KL(i,:) = app.Right_Knee_norm(i+1,:) - app.Right_Knee_norm(i,:);    
    
        end

        L = sqrt(app.KL.^2+app.HL.^2);

        cost = app.HL./L;   % cos(theta) = x(hip)/L
        sint = app.KL./L;   %sin(theta)= y(knee)/L

        for i=1:length(cost)
            app.acost(i,:) = mean(cost(i,:));
            app.asint(i,:) = mean(sint(i,:));
        end

        A = sqrt(app.acost.^2+app.asint.^2);

        Right_ACC = sum(A)/app.FRM;
        app.EditField.Value = Right_ACC;       
        
        end

        % Button pushed function: LeftACCButton
        function LeftACCButtonPushed(app, event)
        app.HL_L = [];
        app.KL_L = [];
        app.acost_L = [];
        app.asint_L = [];
        
        %% Left
        
        app.Pelvis_x_L = app.Pelvis(:,1);                                      
        app.Left_foot_x = app.LeftF(:,1);                                               
        app.Left_foot_z = app.LeftF(:,3);                                               
        app.Left_T = app.Left_foot_x-app.Pelvis_x_L;

        app.Left_HS = islocalmax(app.Left_T,'MinProminence',.1);                           
        app.Left_HS_Time = app.time(app.Left_HS);                                            
        app.index_L = 1:length(app.time);
        app.Left_HS_Index = app.index_L(app.Left_HS);                                          
        app.L_middle_25 = ceil(length(app.Left_HS_Index)*.25);                               
        app.L_middle_50 = ceil(length(app.Left_HS_Index)*.75);                              
        app.Left_HS_Index_mid = app.Left_HS_Index(app.L_middle_25:app.L_middle_50);
                
        for i=1:length(app.Left_HS_Index_mid)-1                                           
            app.Left_period(i) = app.time((app.Left_HS_Index_mid(i+1)-1))-app.time((app.Left_HS_Index_mid(i)));
        end

        app.period_mean = mean(app.Left_period);                                          

        for i=1:length(app.Left_HS_Index_mid)-1                                           
            app.Time_bin_L{i} = app.time(app.Left_HS_Index_mid(i):(app.Left_HS_Index_mid(i+1)-1));
        end
                                                        
        app.jLeftHip = app.jLeftHip(:,end);                                              

        for i=1:length(app.Left_HS_Index_mid)-1                                           
            app.Left_Hipbin{i} = app.jLeftHip(app.Left_HS_Index_mid(i):(app.Left_HS_Index_mid(i+1)-1));
        end

        app.jLeftKnee = app.jLeftKnee(:,end);

        for i=1:length(app.Left_HS_Index_mid)-1                                           
            app.Left_Kneebin{i} = app.jLeftKnee(app.Left_HS_Index_mid(i):app.Left_HS_Index_mid(i+1)-1);
        end

        app.FRM_2 = round(mean(cellfun(@length,app.Left_Hipbin)));             

        for i =1:length(app.Time_bin_L)
            x = app.Time_bin_L{i}';
            T1 = x(1);
            T2 = x(end);
            T3 = T2 - T1;
            xq = T1:(T3/(app.FRM_2 - 1)):T2;
            v1 = app.Left_Hipbin{i}';
            v2 = app.Left_Kneebin{i}';
    
            app.Left_Hip_norm(:,i) = interp1(x,v1,xq,'spline')';
            app.Left_Knee_norm(:,i) = interp1(x,v2,xq,'spline')';

        end

        for i=1:app.FRM_2-1
   
            app.HL_L(i,:) = app.Left_Hip_norm(i+1,:) - app.Left_Hip_norm(i,:);
            app.KL_L(i,:) = app.Left_Knee_norm(i+1,:) - app.Left_Knee_norm(i,:);    
    
        end

        L = sqrt(app.KL_L.^2+app.HL_L.^2);

        cost = app.HL_L./L;   % cos(theta) = x(hip)/L
        sint = app.KL_L./L;   %sin(theta)= y(knee)/L

        for i=1:length(cost)
            app.acost_L(i,:) = mean(cost(i,:));
            app.asint_L(i,:) = mean(sint(i,:));
        end

        A = sqrt(app.acost_L.^2+app.asint_L.^2);

        Left_ACC = sum(A)/app.FRM_2;
        app.EditField_2.Value = Left_ACC;       
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hip Angle (x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Knee Angle (y)')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.RightACCButton = uibutton(app.UIFigure, 'push');
            app.RightACCButton.ButtonPushedFcn = createCallbackFcn(app, @RightACCButtonPushed, true);
            app.RightACCButton.Text = 'Right ACC';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LeftACCButton = uibutton(app.UIFigure, 'push');
            app.LeftACCButton.ButtonPushedFcn = createCallbackFcn(app, @LeftACCButtonPushed, true);
            app.LeftACCButton.Text = 'Left ACC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RMP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end