classdef RMP_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LeftExtractionButton   matlab.ui.control.Button
        EditField              matlab.ui.control.NumericEditField
        ACCLabel               matlab.ui.control.Label
        RightExtractionButton  matlab.ui.control.Button
        EditField_2            matlab.ui.control.NumericEditField
        ACCLabel_2             matlab.ui.control.Label
    end

    
    properties (Access = public)
        t; %Data Tree
        s; %Subject
        fr; %Frame Rate
        time; 
        index; 
        sensorData;
        segmentData;
        nSamples;
        p_Segment1;
        right;
        left;
        
    end
    
    methods (Access = public)
        
        function LoadData(app)
            tree = load_mvnx('SCIMS_703_001_S1_pre_walk-1');
            app.t = tree;
            app.s = tree.subject;
            app.fr = tree.subject.frameRate;
            app.time = ((tree.subject.frames.frame.time(4:end-1))/1000)';       
            app.index = tree.subject.frames.frame(4:end).index;
            
            %retrieve sensor labels creates a struct with sensor data
            if isfield(app.s,'sensors') && isstruct(tree.subject.sensors)
            app.sensorData = tree.subject.sensors.sensor;
            end
            
            %retrieve segment labels
            %creates a struct with segment definitions
            if isfield(app.s,'segments') && isstruct(tree.subject.segments)
            app.segmentData = tree.subject.segments.segment;
            end
            
            %retrieve the data frames from the subject
            app.nSamples = length(tree.subject.frames.frame);
            %pre allocate
            %pre allocate some memory for the position of Segment1
            app.p_Segment1 = zeros(app.nSamples,3);
            
            %read the data from the structure e.g. segment 1
            if isfield(tree.subject.frames.frame(1),'position')
                for i=(1:app.nSamples)
                    app.p_Segment1(i,:)=tree.subject.frames.frame(i).position(1:3);
                end
            end
             
            run('SegmentPosition.m');
            run('JointAngle.m');
                        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LoadData(app)
        end

        % Button pushed function: LeftExtractionButton
        function LeftExtractionButtonPushed(app, event)
            app.left = run('C:\Users\Jessica S. Gilliam\Documents\MATLAB\ShepherdCenter\RMP\Left_extraction.m');
        end

        % Button pushed function: RightExtractionButton
        function RightExtractionButtonPushed(app, event)
            app.right = run('C:\Users\Jessica S. Gilliam\Documents\MATLAB\ShepherdCenter\RMP\Right_extraction.m');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.left.ACC = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.right.ACC = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LeftExtractionButton = uibutton(app.UIFigure, 'push');
            app.LeftExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @LeftExtractionButtonPushed, true);
            app.LeftExtractionButton.Text = 'Left Extraction';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ACCLabel = uilabel(app.UIFigure);
            app.ACCLabel.Text = 'ACC';
            app.RightExtractionButton = uibutton(app.UIFigure, 'push');
            app.RightExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @RightExtractionButtonPushed, true);
            app.RightExtractionButton.Text = 'Right Extraction';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.ACCLabel_2 = uilabel(app.UIFigure);
            app.ACCLabel_2.Text = 'ACC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RMP_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end