classdef RMP_New < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        RightACCButton  matlab.ui.control.Button
        EditField_2     matlab.ui.control.NumericEditField
        LeftACCButton   matlab.ui.control.Button
        EditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        tree; %data
        L; %left
        R; %right
    end
    
    methods (Access = private)
        
        function Load_Plot(app)
            run('main_mvnx.m');
            app.tree = tree;
            plot(app.UIAxes,app.Pelvis(:,1));
            
            plot(app.UIAxes_2,app.jLeftKnee(:,2));
            hold on
            plot(app.UIAxes_2,app.jRightKnee(:,2));
            hold on
            legend(app.UIAxes_2,'Left Knee','Right Knee');
            
            tempB = run('Left_extraction.m');
            app.L = tempB.Left_ACC;
            tempC = run('Right_extraction.m');
            app.R = tempC.Right_ACC;
                       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Load_Plot(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hip Angle (x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Knee Angle (y)')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.RightACCButton = uibutton(app.UIFigure, 'push');
            app.RightACCButton.Text = 'Right ACC';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LeftACCButton = uibutton(app.UIFigure, 'push');
            app.LeftACCButton.Text = 'Left ACC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RMP_New

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end