classdef Xsens_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        Plot_LButton      matlab.ui.control.Button
        Plot_RButton      matlab.ui.control.Button
        ExportButton      matlab.ui.control.Button
        EditField         matlab.ui.control.NumericEditField
        ParticipantLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        set; p; data; tree; ExcelFolder; XsensData;A;B;C;D;E;F
        
    end
    
    methods (Access = private)
        
        function Load_Data(app)
            
            app.set = [1,5,6];
            
            for y = 1:length(app.set) 
                app.p = app.set(y);
                app.tree.data(y) = load_mvnx(sprintf('SCIMS_703_00%d_S1_pre_walk-1',app.p));
                               
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Load_Data(app)
        end

        % Button pushed function: Plot_LButton
        function Plot_LButtonPushed(app, event)
             for value = app.EditField.Value
                reset(app.UIAxes)
                xlabel(app.UIAxes,'Hip [x]');
                ylabel(app.UIAxes,'Knee [y]');
                title(app.UIAxes, 'Left');
                
                
                Pelvis_l = app.tree.data(value).jointData(15).jointAngle(:,1);
                Knee_l = app.tree.data(value).jointData(20).jointAngle(:,2);
                
                plot(app.UIAxes,Pelvis_l,Knee_l);
                
                
             end
        end

        % Button pushed function: Plot_RButton
        function Plot_RButtonPushed(app, event)
            for value = app.EditField.Value
                 reset(app.UIAxes2)
                 xlabel(app.UIAxes2,'Hip [x]');
                 ylabel(app.UIAxes2,'Knee [y]');
                 title(app.UIAxes2,'Right');
                 
                 Pelvis_r = app.tree.data(value).jointData(19).jointAngle(:,1);
                 Knee_r = app.tree.data(value).jointData(16).jointAngle(:,2);
                 plot(app.UIAxes2,Pelvis_r,Knee_r);
                 
                 
            end
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            for x = 1:22
                app.A{x} = app.tree.data(1).jointData(x).jointAngle; %x, y z
                app.B{x} = app.tree.data(2).jointData(x).jointAngle;
                app.C{x} = app.tree.data(3).jointData(x).jointAngle; 
            end

            for x = 1:23
                app.D{x} = app.tree.data(1).segmentData(x).position; %x y z
                app.E{x} = app.tree.data(2).segmentData(x).position; %x y z
                app.F{x} = app.tree.data(3).segmentData(x).position; %x y z
            end

        % Joint Data Export
        
        Table_1 = table(app.A{1,1}, app.A{1,2}, app.A{1,3}, app.A{1,4}, app.A{1,5}, app.A{1,6},...
             app.A{1,7}, app.A{1,8}, app.A{1,9}, app.A{1,10}, app.A{1,11}, app.A{1,12},...
             app.A{1,13}, app.A{1,14}, app.A{1,15}, app.A{1,16}, app.A{1,17}, app.A{1,18},...
             app.A{1,19}, app.A{1,20}, app.A{1,21}, app.A{1,22});
        Table_1.Properties.VariableNames = {'jL5S1';'jL4L3';'jL1T12';'jT9T8';'jT1C7';...
            'jC1Head';'jRightT4Shoulder';'jRightShoulder';'jRightElbow';'jRightWrist';...
            'jLeftT4Shoulder';'jLeftShoulder';'jLeftElbow';'jLeftWrist';'jRightHip';...
            'jRightKnee';'jRightAnkle';'jRightBallFoot';'jLeftHip';'jLeftKnee';'jLeftAnkle';...
            'jLeftBallFoot'};
        Table_5 = table(app.B{1,1}, app.B{1,2}, app.B{1,3}, app.B{1,4}, app.B{1,5}, app.B{1,6},...
             app.B{1,7}, app.B{1,8}, app.B{1,9}, app.B{1,10}, app.B{1,11}, app.B{1,12},...
             app.B{1,13}, app.B{1,14}, app.B{1,15}, app.B{1,16}, app.B{1,17}, app.B{1,18},...
             app.B{1,19}, app.B{1,20}, app.B{1,21}, app.B{1,22});
        Table_5.Properties.VariableNames = {'jL5S1';'jL4L3';'jL1T12';'jT9T8';'jT1C7';...
            'jC1Head';'jRightT4Shoulder';'jRightShoulder';'jRightElbow';'jRightWrist';...
            'jLeftT4Shoulder';'jLeftShoulder';'jLeftElbow';'jLeftWrist';'jRightHip';...
            'jRightKnee';'jRightAnkle';'jRightBallFoot';'jLeftHip';'jLeftKnee';'jLeftAnkle';...
            'jLeftBallFoot'};
        Table_6 = table(app.C{1,1}, app.C{1,2}, app.C{1,3}, app.C{1,4}, app.C{1,5}, app.C{1,6},...
             app.C{1,7}, app.C{1,8}, app.C{1,9}, app.C{1,10}, app.C{1,11}, app.C{1,12},...
             app.C{1,13}, app.C{1,14}, app.C{1,15}, app.C{1,16}, app.C{1,17}, app.C{1,18},...
             app.C{1,19}, app.C{1,20}, app.C{1,21}, app.C{1,22});
        Table_6.Properties.VariableNames = {'jL5S1';'jL4L3';'jL1T12';'jT9T8';'jT1C7';...
            'jC1Head';'jRightT4Shoulder';'jRightShoulder';'jRightElbow';'jRightWrist';...
            'jLeftT4Shoulder';'jLeftShoulder';'jLeftElbow';'jLeftWrist';'jRightHip';...
            'jRightKnee';'jRightAnkle';'jRightBallFoot';'jLeftHip';'jLeftKnee';'jLeftAnkle';...
            'jLeftBallFoot'};
        
        writetable(Table_1,'Participant_JData.xlsx','Sheet',1); % joint Data
        writetable(Table_5,'Participant_JData.xlsx','Sheet',2);
        writetable(Table_6,'Participant_JData.xlsx','Sheet',3); 
        
        % Segment Data Export
        Table_1a = table(app.D{1,1}, app.D{1,2}, app.D{1,3}, app.D{1,4}, app.D{1,5}, app.D{1,6},...
             app.D{1,7}, app.D{1,8}, app.D{1,9}, app.D{1,10}, app.D{1,11}, app.D{1,12},...
             app.D{1,13}, app.D{1,14}, app.D{1,15}, app.D{1,16}, app.D{1,17}, app.D{1,18},...
             app.D{1,19}, app.D{1,20}, app.D{1,21}, app.D{1,22},app.D{1,23});
        Table_1a.Properties.VariableNames = {'Pelvis';'L5';'L3';'T12';'T8';'Neck';'Head';'RightShoulder';'RightUpperArm';...
    'RightForeArm';'RightHand';'LeftShoulder';'LeftUpperArm';'LeftForeArm';...
    'LeftHand';'RightUpperLeg';'RightLowerLeg';'RightFoot';'RightToe';'LeftUpperLeg';...
    'LeftLowerLeg';'LeftFoot';'LeftToe'};
        Table_5a = table(app.E{1,1}, app.E{1,2}, app.E{1,3}, app.E{1,4}, app.E{1,5}, app.E{1,6},...
             app.E{1,7}, app.E{1,8}, app.E{1,9}, app.E{1,10}, app.E{1,11}, app.E{1,12},...
             app.E{1,13}, app.E{1,14}, app.E{1,15}, app.E{1,16}, app.E{1,17}, app.E{1,18},...
             app.E{1,19}, app.E{1,20}, app.E{1,21}, app.E{1,22}, app.E{1,23});
        Table_5a.Properties.VariableNames = {'Pelvis';'L5';'L3';'T12';'T8';'Neck';'Head';'RightShoulder';'RightUpperArm';...
    'RightForeArm';'RightHand';'LeftShoulder';'LeftUpperArm';'LeftForeArm';...
    'LeftHand';'RightUpperLeg';'RightLowerLeg';'RightFoot';'RightToe';'LeftUpperLeg';...
    'LeftLowerLeg';'LeftFoot';'LeftToe'};
        Table_6a = table(app.F{1,1}, app.F{1,2}, app.F{1,3}, app.F{1,4}, app.F{1,5}, app.F{1,6},...
             app.F{1,7}, app.F{1,8}, app.F{1,9}, app.F{1,10}, app.F{1,11}, app.F{1,12},...
             app.F{1,13}, app.F{1,14}, app.F{1,15}, app.F{1,16}, app.F{1,17}, app.F{1,18},...
             app.F{1,19}, app.F{1,20}, app.F{1,21}, app.F{1,22}, app.F{1,23});
        Table_6a.Properties.VariableNames = {'Pelvis';'L5';'L3';'T12';'T8';'Neck';'Head';'RightShoulder';'RightUpperArm';...
    'RightForeArm';'RightHand';'LeftShoulder';'LeftUpperArm';'LeftForeArm';...
    'LeftHand';'RightUpperLeg';'RightLowerLeg';'RightFoot';'RightToe';'LeftUpperLeg';...
    'LeftLowerLeg';'LeftFoot';'LeftToe'};
        
        writetable(Table_1a,'Participant_SegData.xlsx','Sheet',1); % segment Data
        writetable(Table_5a,'Participant_SegData.xlsx','Sheet',2);
        writetable(Table_6a,'Participant_SegData.xlsx','Sheet',3);
        
%         for y = 1:length(app.set)
%             
%             e = actxserver('Excel.Application'); % # open Activex server
%             ewb = e.Workbooks.Open('C:\Users\Jessica S. Gilliam\Documents\MATLAB\ShepherdCenter\RMP\Participant_JData.xlsx'); % # open file (enter full path!) location of file to be written to
%             
%             sheetname = sprintf('P_JData_%d',app.set);
%             ewb.Worksheets.Item(y).Name = sheetname; % # rename 1st
%             
%             ewb = e.Workbooks.Open('C:\Users\Jessica S. Gilliam\Documents\MATLAB\ShepherdCenter\RMP\Participant_SegData.xlsx'); % # open file (enter full path!) location of file to be written to
%             
%             sheetname2 = sprintf('P_SegData_%d',app.set);
%             ewb.Worksheets.Item(y).Name = sheetname2; % # rename 2nd
%             
%             ewb.Save % # save to the same file
%             ewb.Close(false)
%             e.Quit
%         end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Left')
            xlabel(app.UIAxes, 'Hip [x]')
            ylabel(app.UIAxes, 'Knee [y]')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Right')
            xlabel(app.UIAxes2, 'Hip [x]')
            ylabel(app.UIAxes2, 'Knee [y]')
            app.Plot_LButton = uibutton(app.UIFigure, 'push');
            app.Plot_LButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_LButtonPushed, true);
            app.Plot_LButton.Text = 'Plot_L';
            app.Plot_RButton = uibutton(app.UIFigure, 'push');
            app.Plot_RButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_RButtonPushed, true);
            app.Plot_RButton.Text = 'Plot_R';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.ParticipantLabel = uilabel(app.UIFigure);
            app.ParticipantLabel.Text = 'Participant #';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xsens_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end