classdef Xsens_APP_Jas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        PlotAnkleAngleButton          matlab.ui.control.Button
        ExportButton                  matlab.ui.control.Button
        EditField                     matlab.ui.control.NumericEditField
        ParticipantLabel              matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        MaxDorsiflexionAngleLabel     matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        VolitionalRangeofMotionLabel  matlab.ui.control.Label
        OutputDataButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        set; p; data; tree; ExcelFolder; XsensData;A;B;C;D;E;F;R_ankle;L_ankle;dorsi;vol;
        
    end
    
    methods (Access = private)
        
        function Load_Data(app)
            
            app.set = (1); % alter here for more participants
            
            for y = 1:length(app.set) 
                app.p = app.set(y);
                app.tree.data(y) = load_mvnx(sprintf('P18_T1_L_D%d',app.p)); %check %d when more participants
                               
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Load_Data(app)
        end

        % Button pushed function: PlotAnkleAngleButton
        function PlotAnkleAngleButtonPushed(app, event)
             for value = app.EditField.Value
                reset(app.UIAxes)
                
%                 xlabel(app.UIAxes,'Hip [x]');
%                 ylabel(app.UIAxes,'Knee [y]');
                title(app.UIAxes, 'Ankle Angle');
                                
                app.R_ankle = app.tree.data(value).jointData(17).jointAngle(:,3); %dorsiflexion is z axis
                app.L_ankle = app.tree.data(value).jointData(21).jointAngle(:,3);
                
                plot(app.UIAxes,app.R_ankle);
                hold(app.UIAxes)
                plot(app.UIAxes,app.L_ankle);
                legend(app.UIAxes,'R ankle','L ankle','Location','bestoutside');
                
             end
        end

        % Callback function
        function Plot_RButtonPushed(app, event)
            
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            for x = 1:22
                app.A{x} = app.tree.data(1).jointData(x).jointAngle; %x, y z
%                 app.B{x} = app.tree.data(2).jointData(x).jointAngle;
%                 app.C{x} = app.tree.data(3).jointData(x).jointAngle; 
            end

            for x = 1:23
                app.D{x} = app.tree.data(1).segmentData(x).position; %x y z
%                 app.E{x} = app.tree.data(2).segmentData(x).position; %x y z
%                 app.F{x} = app.tree.data(3).segmentData(x).position; %x y z
            end

        % Joint Data Export
        
        Table_1 = table(app.A{1,1}, app.A{1,2}, app.A{1,3}, app.A{1,4}, app.A{1,5}, app.A{1,6},...
             app.A{1,7}, app.A{1,8}, app.A{1,9}, app.A{1,10}, app.A{1,11}, app.A{1,12},...
             app.A{1,13}, app.A{1,14}, app.A{1,15}, app.A{1,16}, app.A{1,17}, app.A{1,18},...
             app.A{1,19}, app.A{1,20}, app.A{1,21}, app.A{1,22});
        Table_1.Properties.VariableNames = {'jL5S1';'jL4L3';'jL1T12';'jT9T8';'jT1C7';...
            'jC1Head';'jRightT4Shoulder';'jRightShoulder';'jRightElbow';'jRightWrist';...
            'jLeftT4Shoulder';'jLeftShoulder';'jLeftElbow';'jLeftWrist';'jRightHip';...
            'jRightKnee';'jRightAnkle';'jRightBallFoot';'jLeftHip';'jLeftKnee';'jLeftAnkle';...
            'jLeftBallFoot'};
%         Table_5 = table(app.B{1,1}, app.B{1,2}, app.B{1,3}, app.B{1,4}, app.B{1,5}, app.B{1,6},...
%              app.B{1,7}, app.B{1,8}, app.B{1,9}, app.B{1,10}, app.B{1,11}, app.B{1,12},...
%              app.B{1,13}, app.B{1,14}, app.B{1,15}, app.B{1,16}, app.B{1,17}, app.B{1,18},...
%              app.B{1,19}, app.B{1,20}, app.B{1,21}, app.B{1,22});
%         Table_5.Properties.VariableNames = {'jL5S1';'jL4L3';'jL1T12';'jT9T8';'jT1C7';...
%             'jC1Head';'jRightT4Shoulder';'jRightShoulder';'jRightElbow';'jRightWrist';...
%             'jLeftT4Shoulder';'jLeftShoulder';'jLeftElbow';'jLeftWrist';'jRightHip';...
%             'jRightKnee';'jRightAnkle';'jRightBallFoot';'jLeftHip';'jLeftKnee';'jLeftAnkle';...
%             'jLeftBallFoot'};
%         Table_6 = table(app.C{1,1}, app.C{1,2}, app.C{1,3}, app.C{1,4}, app.C{1,5}, app.C{1,6},...
%              app.C{1,7}, app.C{1,8}, app.C{1,9}, app.C{1,10}, app.C{1,11}, app.C{1,12},...
%              app.C{1,13}, app.C{1,14}, app.C{1,15}, app.C{1,16}, app.C{1,17}, app.C{1,18},...
%              app.C{1,19}, app.C{1,20}, app.C{1,21}, app.C{1,22});
%         Table_6.Properties.VariableNames = {'jL5S1';'jL4L3';'jL1T12';'jT9T8';'jT1C7';...
%             'jC1Head';'jRightT4Shoulder';'jRightShoulder';'jRightElbow';'jRightWrist';...
%             'jLeftT4Shoulder';'jLeftShoulder';'jLeftElbow';'jLeftWrist';'jRightHip';...
%             'jRightKnee';'jRightAnkle';'jRightBallFoot';'jLeftHip';'jLeftKnee';'jLeftAnkle';...
%             'jLeftBallFoot'};
        
        writetable(Table_1,'Participant_Jas.xlsx','Sheet',1); % joint Data
%         writetable(Table_5,'Participant_1_JData.xlsx','Sheet',2);
%         writetable(Table_6,'Participant_1_JData.xlsx','Sheet',3); 
        
        % Segment Data Export
        Table_1a = table(app.D{1,1}, app.D{1,2}, app.D{1,3}, app.D{1,4}, app.D{1,5}, app.D{1,6},...
             app.D{1,7}, app.D{1,8}, app.D{1,9}, app.D{1,10}, app.D{1,11}, app.D{1,12},...
             app.D{1,13}, app.D{1,14}, app.D{1,15}, app.D{1,16}, app.D{1,17}, app.D{1,18},...
             app.D{1,19}, app.D{1,20}, app.D{1,21}, app.D{1,22},app.D{1,23});
        Table_1a.Properties.VariableNames = {'Pelvis';'L5';'L3';'T12';'T8';'Neck';'Head';'RightShoulder';'RightUpperArm';...
    'RightForeArm';'RightHand';'LeftShoulder';'LeftUpperArm';'LeftForeArm';...
    'LeftHand';'RightUpperLeg';'RightLowerLeg';'RightFoot';'RightToe';'LeftUpperLeg';...
    'LeftLowerLeg';'LeftFoot';'LeftToe'};
%         Table_5a = table(app.E{1,1}, app.E{1,2}, app.E{1,3}, app.E{1,4}, app.E{1,5}, app.E{1,6},...
%              app.E{1,7}, app.E{1,8}, app.E{1,9}, app.E{1,10}, app.E{1,11}, app.E{1,12},...
%              app.E{1,13}, app.E{1,14}, app.E{1,15}, app.E{1,16}, app.E{1,17}, app.E{1,18},...
%              app.E{1,19}, app.E{1,20}, app.E{1,21}, app.E{1,22}, app.E{1,23});
%         Table_5a.Properties.VariableNames = {'Pelvis';'L5';'L3';'T12';'T8';'Neck';'Head';'RightShoulder';'RightUpperArm';...
%     'RightForeArm';'RightHand';'LeftShoulder';'LeftUpperArm';'LeftForeArm';...
%     'LeftHand';'RightUpperLeg';'RightLowerLeg';'RightFoot';'RightToe';'LeftUpperLeg';...
%     'LeftLowerLeg';'LeftFoot';'LeftToe'};
%         Table_6a = table(app.F{1,1}, app.F{1,2}, app.F{1,3}, app.F{1,4}, app.F{1,5}, app.F{1,6},...
%              app.F{1,7}, app.F{1,8}, app.F{1,9}, app.F{1,10}, app.F{1,11}, app.F{1,12},...
%              app.F{1,13}, app.F{1,14}, app.F{1,15}, app.F{1,16}, app.F{1,17}, app.F{1,18},...
%              app.F{1,19}, app.F{1,20}, app.F{1,21}, app.F{1,22}, app.F{1,23});
%         Table_6a.Properties.VariableNames = {'Pelvis';'L5';'L3';'T12';'T8';'Neck';'Head';'RightShoulder';'RightUpperArm';...
%     'RightForeArm';'RightHand';'LeftShoulder';'LeftUpperArm';'LeftForeArm';...
%     'LeftHand';'RightUpperLeg';'RightLowerLeg';'RightFoot';'RightToe';'LeftUpperLeg';...
%     'LeftLowerLeg';'LeftFoot';'LeftToe'};
        
        writetable(Table_1a,'Participant_Jas.xlsx','Sheet',2); % segment Data
%         writetable(Table_5a,'Participant_1_SegData.xlsx','Sheet',2);
%         writetable(Table_6a,'Participant_1_SegData.xlsx','Sheet',3);

        Table_1b = table(app.vol, app.dorsi);
        Table_1b.Properties.VariableNames = {'Volitional Range';'Maximum Dorsiflexion'};
        writetable(Table_1b,'Participant_Jas.xlsx','Sheet',3);
        end

        % Button pushed function: OutputDataButton
        function OutputDataButtonPushed(app, event)
            for value = app.EditField.Value
                            
                app.vol = max(abs(app.L_ankle)) - min(abs(app.L_ankle));
                app.EditField_2.Value = app.vol;
                %app.EditField_3.Value = 
                
                app.dorsi = max(abs(app.L_ankle));
                app.EditField_3.Value = app.dorsi;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ankle Angle')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PlotAnkleAngleButton = uibutton(app.UIFigure, 'push');
            app.PlotAnkleAngleButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAnkleAngleButtonPushed, true);
            app.PlotAnkleAngleButton.Text = 'Plot Ankle Angle';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.ParticipantLabel = uilabel(app.UIFigure);
            app.ParticipantLabel.Text = 'Participant #';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MaxDorsiflexionAngleLabel = uilabel(app.UIFigure);
            app.MaxDorsiflexionAngleLabel.Text = 'Max Dorsiflexion Angle';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.VolitionalRangeofMotionLabel = uilabel(app.UIFigure);
            app.VolitionalRangeofMotionLabel.Text = 'Volitional Range of Motion';
            app.OutputDataButton = uibutton(app.UIFigure, 'push');
            app.OutputDataButton.ButtonPushedFcn = createCallbackFcn(app, @OutputDataButtonPushed, true);
            app.OutputDataButton.Text = 'Output Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xsens_APP_Jas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end