classdef Xsens_APP_Jas_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ExportButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        set; set2; data; tree; Participant; XsensData;A;B;C;D;E;F;R_ankle;L_ankle;dorsi_1a;vol_1a;
        
    end
    
    methods (Access = private)
        
        function Load_Data(app)
            
            app.set = [2, 4, 5, 6, 8, 9, 13, 14, 15, 16, 18]; % alter here for participant specific ID's
            app.set2 = 2;
            % This set for P2 (only 2 trials & Right leg only & only 2 D)
            for y = 1:length(app.set2)
                p = app.set2(y);
                for t = 1:2
                   for d = 1:2
                       app.tree.data = load_mvnx(sprintf('P%d_T%d_R_D%d',p,t,d));
                       
                       Dorsi_L = app.tree.data.jointData(21).jointAngle(:,3); %dorsiflexion in z axis L
                       Dorsi_R = app.tree.data.jointData(17).jointAngle(:,3); %dorsiflexion in z axis R
                       
                       Vol_L = max(abs(Dorsi_L)) - min(abs(Dorsi_L)); %Left
                       Vol_R = max(abs(Dorsi_R)) - min(abs(Dorsi_R)); %Right
                       
                       Dor_L = max(abs(Dorsi_L)); % Left
                       Dor_R =max(abs(Dorsi_R)); %Right
                       
                       app.Participant2(y,:) = [p, Vol_L,Vol_R,Dor_L,Dor_R];
                       
                   end
               end
            
            end
            
            % This set for all other trials
            for y = 1:length(app.set)
                p = app.set(y);
                for t = 1:4
                   for d = 1:3
                       app.tree.data = load_mvnx(sprintf('P%d_T%d_R_D%d',p,t,d));
                       
                       Dorsi_L = app.tree.data.jointData(21).jointAngle(:,3); %dorsiflexion in z axis L
                       Dorsi_R = app.tree.data.jointData(17).jointAngle(:,3); %dorsiflexion in z axis R
                       
                       Vol_L = max(abs(Dorsi_L)) - min(abs(Dorsi_L)); %Left
                       Vol_R = max(abs(Dorsi_R)) - min(abs(Dorsi_R)); %Right
                       
                       Dor_L = max(abs(Dorsi_L)); %Left
                       Dor_R =max(abs(Dorsi_R)); %Right
                       
                       app.Participant(y,:) = [p, Vol_L,Vol_R,Dor_L,Dor_R];
                       
                   end
               end
            
            end
            writetable(app.Participant2,'Participant_Jas.xlsx','Sheet',1)
            writetable(app.Participant,'Participant_Jas.xlsx','Sheet',2)
        end
    end
     

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Load_Data(app)
        end

        % Callback function
        function PlotAnkleAngleButtonPushed(app, event)
%              
        end

        % Callback function
        function Plot_RButtonPushed(app, event)
            
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
%              
        end

        % Callback function
        function OutputDataButtonPushed(app, event)
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xsens_APP_Jas_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end