classdef Xsens_APP_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LeftACCEditField         matlab.ui.control.NumericEditField
        LeftACCEditFieldLabel    matlab.ui.control.Label
        RightACCEditField        matlab.ui.control.NumericEditField
        RightACCEditFieldLabel   matlab.ui.control.Label
        UITable2                 matlab.ui.control.Table
        UITable                  matlab.ui.control.Table
        Tree2                    matlab.ui.container.Tree
        WalkingNode_2            matlab.ui.container.TreeNode
        HS_TO_RNode              matlab.ui.container.TreeNode
        HS_TO_LNode              matlab.ui.container.TreeNode
        ACCNode_2                matlab.ui.container.TreeNode
        Tree                     matlab.ui.container.Tree
        JointDataNode            matlab.ui.container.TreeNode
        jL5S1Node                matlab.ui.container.TreeNode
        XNode                    matlab.ui.container.TreeNode
        YNode                    matlab.ui.container.TreeNode
        ZNode                    matlab.ui.container.TreeNode
        jL4L3Node                matlab.ui.container.TreeNode
        XNode_2                  matlab.ui.container.TreeNode
        YNode_2                  matlab.ui.container.TreeNode
        ZNode_2                  matlab.ui.container.TreeNode
        jL1T12Node               matlab.ui.container.TreeNode
        XNode_22                 matlab.ui.container.TreeNode
        YNode_21                 matlab.ui.container.TreeNode
        ZNode_21                 matlab.ui.container.TreeNode
        jT9T8Node                matlab.ui.container.TreeNode
        XNode_3                  matlab.ui.container.TreeNode
        YNode_3                  matlab.ui.container.TreeNode
        ZNode_3                  matlab.ui.container.TreeNode
        jT1C7Node                matlab.ui.container.TreeNode
        XNode_5                  matlab.ui.container.TreeNode
        YNode_4                  matlab.ui.container.TreeNode
        ZNode_4                  matlab.ui.container.TreeNode
        jC1HeadNode              matlab.ui.container.TreeNode
        XNode_23                 matlab.ui.container.TreeNode
        YNode_22                 matlab.ui.container.TreeNode
        ZNode_22                 matlab.ui.container.TreeNode
        jRightT4ShoulderNode     matlab.ui.container.TreeNode
        XNode_6                  matlab.ui.container.TreeNode
        YNode_5                  matlab.ui.container.TreeNode
        ZNode_5                  matlab.ui.container.TreeNode
        jRightShoulderNode       matlab.ui.container.TreeNode
        XNode_7                  matlab.ui.container.TreeNode
        YNode_6                  matlab.ui.container.TreeNode
        ZNode_6                  matlab.ui.container.TreeNode
        jRightElbowNode          matlab.ui.container.TreeNode
        XNode_8                  matlab.ui.container.TreeNode
        YNode_7                  matlab.ui.container.TreeNode
        ZNode_7                  matlab.ui.container.TreeNode
        jRightWristNode          matlab.ui.container.TreeNode
        XNode_9                  matlab.ui.container.TreeNode
        YNode_8                  matlab.ui.container.TreeNode
        ZNode_8                  matlab.ui.container.TreeNode
        jLeftT4ShoulderNode      matlab.ui.container.TreeNode
        XNode_10                 matlab.ui.container.TreeNode
        YNode_9                  matlab.ui.container.TreeNode
        ZNode_9                  matlab.ui.container.TreeNode
        jLeftShoulderNode        matlab.ui.container.TreeNode
        XNode_11                 matlab.ui.container.TreeNode
        YNode_10                 matlab.ui.container.TreeNode
        ZNode_10                 matlab.ui.container.TreeNode
        jLeftElbowNode           matlab.ui.container.TreeNode
        XNode_12                 matlab.ui.container.TreeNode
        YNode_11                 matlab.ui.container.TreeNode
        ZNode_11                 matlab.ui.container.TreeNode
        jLeftWristNode           matlab.ui.container.TreeNode
        XNode_13                 matlab.ui.container.TreeNode
        YNode_12                 matlab.ui.container.TreeNode
        ZNode_12                 matlab.ui.container.TreeNode
        jRightHipNode            matlab.ui.container.TreeNode
        XNode_14                 matlab.ui.container.TreeNode
        YNode_13                 matlab.ui.container.TreeNode
        ZNode_13                 matlab.ui.container.TreeNode
        jRightKneeNode           matlab.ui.container.TreeNode
        XNode_15                 matlab.ui.container.TreeNode
        YNode_14                 matlab.ui.container.TreeNode
        ZNode_14                 matlab.ui.container.TreeNode
        jRightAnkleNode          matlab.ui.container.TreeNode
        XNode_16                 matlab.ui.container.TreeNode
        YNode_15                 matlab.ui.container.TreeNode
        ZNode_15                 matlab.ui.container.TreeNode
        jRightBallFootNode       matlab.ui.container.TreeNode
        XNode_17                 matlab.ui.container.TreeNode
        YNode_16                 matlab.ui.container.TreeNode
        ZNode_16                 matlab.ui.container.TreeNode
        jLeftHipNode             matlab.ui.container.TreeNode
        XNode_18                 matlab.ui.container.TreeNode
        YNode_17                 matlab.ui.container.TreeNode
        ZNode_17                 matlab.ui.container.TreeNode
        jLeftKneeNode            matlab.ui.container.TreeNode
        XNode_19                 matlab.ui.container.TreeNode
        YNode_18                 matlab.ui.container.TreeNode
        ZNode_18                 matlab.ui.container.TreeNode
        jLeftAnkleNode           matlab.ui.container.TreeNode
        XNode_20                 matlab.ui.container.TreeNode
        YNode_19                 matlab.ui.container.TreeNode
        ZNode_19                 matlab.ui.container.TreeNode
        jLeftBallFootNode        matlab.ui.container.TreeNode
        XNode_21                 matlab.ui.container.TreeNode
        YNode_20                 matlab.ui.container.TreeNode
        ZNode_20                 matlab.ui.container.TreeNode
        RelativeMotionPlotsNode  matlab.ui.container.TreeNode
        R_HipXvsKneeYNode        matlab.ui.container.TreeNode
        L_HipXvsKneeYNode        matlab.ui.container.TreeNode
        R_HipKneeAnkleNode       matlab.ui.container.TreeNode
        XNode_24                 matlab.ui.container.TreeNode
        YNode_23                 matlab.ui.container.TreeNode
        ZNode_23                 matlab.ui.container.TreeNode
        L_HipKneeAnkleNode       matlab.ui.container.TreeNode
        XNode_25                 matlab.ui.container.TreeNode
        YNode_24                 matlab.ui.container.TreeNode
        ZNode_24                 matlab.ui.container.TreeNode
        ExportButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tree; files;str;JData;JData_x;JData_y;JData_z; prompt;fileList;TData;usersValue1;N;y; % Description
    end
    
    methods (Access = private)
        
        function LoadData(app)
          
            app.prompt = questdlg('How do you want to import your data?',...
                'GUI','Import Folder','Import File','Import File');
            
            switch app.prompt
                
                case 'Import Folder'
                
                %uigetdir('C:\Users\Jessica S. Gilliam\Documents\MATLAB\ShepherdCenter'); % Set directory
                app.files = dir('*.mvnx');
                app.fileList = dir('*.*');
                
                files = (dir ('*.mvnx')); %list of mvnx files
                app.y = ({files.name})';
                
                    for i = 1:length(app.files)
                        app.tree.data(i) = load_mvnx(app.files(i).name);
                    end
                    
                case 'Import File'
                
                    %uigetdir('C:\Users\Jessica S. Gilliam\Documents\MATLAB\ShepherdCenter'); % Set directory
                    app.files = uigetfile('*.mvnx');
                    app.y = ({app.files})';
                    app.fileList = dir('*.*');
                    app.tree.data = load_mvnx(app.files);
                
            end
            
                     
        end
        
        
        function ExportData(app)
           
%             if app.prompt 
%                 
%                 for i = 1:length(app.files)
%                     for j = 1:22
%                         for s = 1:23
%                             Table_S = app.tree.data(i).segmentData(s).orientation;
%                         end
%                         Table_J = app.tree.data(i).jointData(j).jointAngle;
%                     end
%                                   
%                        
%                     y_1 = num2str(i);
%                     
%                     filename = 'Participant_JointData.xls';
%                     writematrix(Table_J, filename,'Sheet',y_1);
%                 end
%             else
%                 
%                 filename = 'Participant_JointData.xls';
%                 writematrix(app.tree.data,filename,'Sheet',y_1);
%             end
        end
        
%         function Analyze(app)
            
%             promptn = input('Select Analysis Type:[JointAngle(1)] ');
%                     
%                     if promptn
%                         
%                         app.str = input('What joint are you looking at? Write specific Xsens joint name: ','s');
%                                                 
%                         C = {'jL5S1','jL4L3','jL1T12','jT9T8','jT1C7','jC1Head','jRightT4Shoulder','jRightShoulder',...
%                         'jRightElbow','jRightWrist','jLeftT4Shoulder','jLeftShoulder','jLeftElbow','jLeftWrist',...
%                         'jRightHip','jRightKnee','jRightAnkle','jRightBallFoot','jLeftHip','jLeftKnee','jLeftAnkle',...
%                         'jLeftBallFoot'};
%                     
%                         idx = strcmpi(app.str, C);
%                         [r,c] = find(idx);
%                         
%                         prompt2 = input('What Axis? X(2), Y(1), Z(0): ');
%                                                 
%                         prompt3 = input('What Participant are you analyzing? [ID num]: ');
%                         set = [1,5,6]; % this can be changed here
%                         [a,b] = find(set == prompt3);
%                         app.JData = app.tree.data(b).jointData(c).jointAngle(:,prompt2);
%                                               
%                         
%                     end
            
%         end
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LoadData(app)
            app.Tree.Multiselect = 'on';
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%5
% Updating Table View
            %files = (dir ('*.mvnx')); %list of mvnx files
            %y = ({files.name})';
            checkbox = (repelem({false},length(app.y)))';
            app.TData = [app.y checkbox];
            
            app.UITable.Data = app.TData;

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            ExportData(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
% 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Selection of Axis
% 
%             defaultValue = {'1','1','1','1'};
               
%             titleBar = 'Values';
%             userPrompt = {'Analyze X? Y[1]/N[0]: ','Analyze Y? Y[1]/N[0]: ','Analyze Z? Y[1]/N[0]: '};
%             caUserInput = inputdlg(userPrompt, titleBar, 1, defaultValue);
%                 if isempty(caUserInput),return,end % Bail out if they clicked Cancel.
%                     
%                     %app.usersValue1 = str2double(caUserInput{1});
%                     usersValue2 = str2double(caUserInput{1});
%                     usersValue3 = str2double(caUserInput{2});
%                     usersValue4 = str2double(caUserInput{3});
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                    
% Cycles through tree Joint Data Selections

            app.Tree.Multiselect = 'on';
            Nodes = event.SelectedNodes;
            switch Nodes
                case app.jL5S1Node
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(1).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(1).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(1).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jL4L3Node
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(2).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(2).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(2).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jL1T12Node
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(3).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(3).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(3).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jT9T8Node
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(4).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(4).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(4).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jT1C7Node
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(5).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(5).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(5).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                        %end
                case app.jC1HeadNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(6).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(6).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(6).jointAngle(:,3);
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jRightT4ShoulderNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(7).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(7).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(7).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jRightShoulderNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(8).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(8).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(8).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jRightElbowNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(9).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(9).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(9).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jRightWristNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(10).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(10).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(10).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jLeftT4ShoulderNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(11).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(11).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(11).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jLeftShoulderNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(12).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(12).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(12).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jLeftElbowNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(13).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(13).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(13).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jLeftWristNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(14).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(14).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(14).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jRightHipNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jRightKneeNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jRightAnkleNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jRightBallFootNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(18).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(18).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(18).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.jLeftHipNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
            
                case app.jLeftKneeNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                    
                case app.jLeftAnkleNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                     
                case app.jLeftBallFootNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(22).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(22).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(22).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
            end
            
  %%%%%%%%%%%%%%%%%% Relative Motion Plots Section
    Nodes = event.SelectedNodes;
            switch Nodes
                case app.R_HipXvsKneeYNode
                   app.JData_x = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,3); %Hip
                   app.JData_y = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,3); %Knee
                   plot(app.UIAxes,app.JData_x,app.JData_y)
                   app.UITable2.Data = [app.JData_x, app.JData_y];
                   
                case app.L_HipXvsKneeYNode
                   app.JData_x = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,3);
                   app.JData_y = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,3);
                   plot(app.UIAxes,app.JData_x,app.JData_y)
                   app.UITable2.Data = [app.JData_x, app.JData_y];
            end

    Nodes = event.SelectedNodes;
            switch Nodes
                %%% Right
                case app.XNode_24
                    JData_x1 = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,1); % Hip
                    JData_x2 = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,1); % Knee
                    JData_x3 = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,1); % Ankle
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_x1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_x2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_x3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                    app.UITable2.Data = [JData_x1, JData_x2, JData_x3];
                    
                case app.YNode_23
                    JData_y1 = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,2);
                    JData_y2 = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,2);
                    JData_y3 = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,2);
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_y1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_y2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_y3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                    app.UITable2.Data = [JData_y1, JData_y2, JData_y3];
                    
                case app.ZNode_23
                    JData_z1 = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,3);
                    JData_z2 = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,3);
                    JData_z3 = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,3);
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_z1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_z2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_z3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                    app.UITable2.Data = [JData_z1, JData_z2, JData_z3];
                    
                %%% Left
                case app.XNode_25
                    JData_x1 = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,1);
                    JData_x2 = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,1);
                    JData_x3 = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,1);
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_x1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_x2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_x3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                     app.UITable2.Data = [JData_x1, JData_x2, JData_x3];
                    
                case app.YNode_24
                    JData_y1 = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,2);
                    JData_y2 = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,2);
                    JData_y3 = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,2);
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_y1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_y2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_y3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                    app.UITable2.Data = [JData_y1, JData_y2, JData_y3];
                    
                case app.ZNode_24
                    JData_z1 = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,3);
                    JData_z2 = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,3);
                    JData_z3 = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,3);
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_z1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_z2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_z3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                    app.UITable2.Data = [JData_z1, JData_z2, JData_z3];
                    
            end
% From selected joint Data - shows x,y,z values and graphical display of x,y,z

%                     T = table(app.JData_x, app.JData_y, app.JData_z);
%                     T.Properties.VariableNames = {'X','Y','Z'};
%                     fig = uifigure('Name',app.N);
%                     fig.Position = [20 20 400 600];
                    
%                     ax = uiaxes(fig);
%                     ax2 = uiaxes(fig);
%                     ax3 = uiaxes(fig);
%                     ax.Position = [400 10 500 200];
%                     ax2.Position = [400 200 500 200];
%                     ax3.Position = [400 400 500 200];
%                     ax.YLabel.String = '[X]';
%                     ax2.YLabel.String = '[Y]';
%                     ax3.YLabel.String = '[Z]';

                reset(app.UIAxes);
                time = str2double({app.tree.data(app.usersValue1).frame.time});
                time = (time)/1000; 


%                 reset(app.UIAxes_2);
%                 reset(app.UIAxes_3);
                
            Nodes = event.SelectedNodes;
            reset(app.UIAxes); 
            switch Nodes
               %%%% Drop Down %%%% 
                case app.XNode
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_2
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_2
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_2
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_22
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_21
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_21
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_3
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_3
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_3
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_5
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_4
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_4
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_23
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_22
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_22
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_6
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_5
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_5
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_7
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_6
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_6
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_8
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_7
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_7
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_9
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_8
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_8
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_10
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_9
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_9
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_11
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_10
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_10
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_12
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_11
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_11
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_13
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_12
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_12
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_14
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_13
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_13
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_15
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_14
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_14
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_16
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_15
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_15
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_17
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_16
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_16
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_18
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_17
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_17
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_19
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_18
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_18
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_20
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_19
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_19
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_21
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_20
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_20
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
            end
                    
                  
%                     ylabel(app.UIAxes, '[X]');
%                     ylabel(app.UIAxes_2, '[Y]');
%                     ylabel(app.UIAxes_3, '[Z]');
                
                    
%                     uit = uitable(fig,'Data',T);
%                     uit.Position = [10 10 400 550];
%                     uit.RowName = 'numbered';
%                     fig.AutoResizeChildren = 'on';


        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
% Pulls data from user defined Participant ID   

          Pelvis_x = app.tree.data(app.usersValue1).segmentData(1).position(:,1);
          R_Foot_x = app.tree.data(app.usersValue1).segmentData(18).position(:,1);
          R_Toe_x = app.tree.data(app.usersValue1).segmentData(19).position(:,1);
          L_Foot_x = app.tree.data(app.usersValue1).segmentData(22).position(:,1);
          L_Toe_x = app.tree.data(app.usersValue1).segmentData(23).position(:,1);
          time = str2double({app.tree.data(app.usersValue1).frame.time});
          time = (time)/1000;
          index = 1:length(time);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%Locates Heel Strike and Toe Off
                    HS_r = islocalmax((R_Foot_x - Pelvis_x),'MinProminence',.1);
                    HS_l = islocalmax((L_Foot_x - Pelvis_x),'MinProminence',.1);
                    
                    HS_r_time = time(HS_r);
                    HS_l_time = time(HS_l);
                    HS_r_index = index(HS_r);
                    HS_l_index = index(HS_l);
                  
                    TO_r = islocalmin((R_Toe_x - Pelvis_x),'Minprominence',.1);
                    TO_l = islocalmin((L_Toe_x - Pelvis_x),'MinProminence',.1);
                    
                    TO_r_time = time(TO_r);
                    TO_l_time = time(TO_l);
                    TO_r_index = index(TO_r);
                    TO_l_index = index(TO_l);
                    
%                     while length(HS_l_time) ~= length(TO_l_time)
%                         if length(HS_l_time) > length(TO_l_time)
%                             HS_l_time(end) = [];
%                             HS_l_index(end) = [];   
% 
%                         elseif length(TO_l_time) > length(HS_l_time)
%                             TO_l_time(end) = [];
%                             TO_l_index(end) = [];
%                         end
%                     end
%                     
%                     while length(HS_r_time) ~= length(TO_r_time)
%                         if length(HS_r_time) > length(TO_r_time)
%                             HS_r_time(end) = [];
%                             HS_r_index(end) = [];   
% 
%                         elseif length(TO_r_time) > length(HS_r_time)
%                             TO_r_time(end) = [];
%                             TO_r_index(end) = [];
%                         end
%                     end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                    
% Changes based on selection of Node                    
          Nodes2 = event.SelectedNodes;
            switch Nodes2
                case app.HS_TO_RNode
                    
                    hold(app.UIAxes);
%                     hold(app.UIAxes_2);
%                     hold(app.UIAxes_3);
                    
                                        
                    TO_r_x = (app.JData_x(TO_r))';
                    HS_r_x = (app.JData_x(HS_r))';
                    TO_r_y = (app.JData_y(TO_r))';
                    HS_r_y = (app.JData_y(HS_r))';
                    TO_r_z = (app.JData_z(TO_r))';
                    HS_r_z = (app.JData_z(HS_r))';
                    
                    plot(app.UIAxes,TO_r_time,TO_r_x,'r*')
                    plot(app.UIAxes,HS_r_time,HS_r_x,'b*')
                    
%                     plot(app.UIAxes_2,TO_r_time,TO_r_y,'r*')
%                     plot(app.UIAxes_2,HS_r_time,HS_r_y,'b*')
                    
%                     plot(app.UIAxes_3,TO_r_time,TO_r_z,'r*')
%                     plot(app.UIAxes_3,HS_r_time,HS_r_z,'b*')
                    
                    legend(app.UIAxes,'Raw Data','Toe Off','Heel Strike');
%                     legend(app.UIAxes_2,'Raw Data','Toe Off','Heel Strike');
%                     legend(app.UIAxes_3,'Raw Data','Toe Off','Heel Strike');
                    
%                     ylabel(app.UIAxes, '[X]');
%                     ylabel(app.UIAxes_2, '[Y]');
%                     ylabel(app.UIAxes_3, '[Z]');
                    
                case app.HS_TO_LNode
                    
                hold(app.UIAxes);
%                 hold(app.UIAxes_2);
%                 hold(app.UIAxes_3);
                
                    TO_l_x = (app.JData_x(TO_l))';
                    HS_l_x = (app.JData_x(HS_l))';
                    TO_l_y = (app.JData_y(TO_l))';
                    HS_l_y = (app.JData_y(HS_l))';
                    TO_l_z = (app.JData_z(TO_l))';
                    HS_l_z = (app.JData_z(HS_l))';
                    
                    plot(app.UIAxes,TO_l_time,TO_l_x,'r*')
                    plot(app.UIAxes,HS_l_time,HS_l_x,'b*')
                    
%                     plot(app.UIAxes_2,TO_l_time,TO_l_y,'r*')
%                     plot(app.UIAxes_2,HS_l_time,HS_l_y,'b*')
%                     
%                     plot(app.UIAxes_3,TO_l_time,TO_l_z,'r*')
%                     plot(app.UIAxes_3,HS_l_time,HS_l_z,'b*')
                    
                    legend(app.UIAxes,'Raw Data','Toe Off','Heel Strike');
%                     legend(app.UIAxes_2,'Raw Data','Toe Off','Heel Strike');
%                     legend(app.UIAxes_3,'Raw Data','Toe Off','Heel Strike');
                    
%                     ylabel(app.UIAxes, '[X]');
%                     ylabel(app.UIAxes_2, '[Y]');
%                     ylabel(app.UIAxes_3, '[Z]');
                       

%                     T = table(HS_l_time', HS_l_index', HS_r_time', HS_r_index');
%                     T.Properties.VariableNames = {'Left Heel Strike time','Left Heel Strike index',...
%                         'Right Heel Strike time','Right Heel Strike index'};
%                     fig = uifigure('Name',app.N);
%                     fig.Position = [140 80 900 600];
%                     uit = uitable(fig,'Data',T);
%                     uit.Position = [50 50 600 450];
%                     uit.RowName = 'numbered';
%                     fig.AutoResizeChildren = 'on';


                case app.ACCNode_2
                    Pelvis_x = app.tree.data(app.usersValue1).segmentData(1).position(:,1);
                    R_Foot_x = app.tree.data(app.usersValue1).segmentData(18).position(:,1);
                    R_Toe_x = app.tree.data(app.usersValue1).segmentData(19).position(:,1);
                    L_Foot_x = app.tree.data(app.usersValue1).segmentData(22).position(:,1);
                    L_Toe_x = app.tree.data(app.usersValue1).segmentData(23).position(:,1);
                    
                    Left_T = L_Foot_x - Pelvis_x;
                    Right_T = R_Foot_x - Pelvis_x;
                    
                    Left_HS = islocalmax(Left_T,'MinProminence',.1); %pulls peaks which are HS 
                    Right_HS=islocalmax(Right_T,'MinProminence',.1);    
                    Left_HS_Time = time(Left_HS);
                    Right_HS_Time = time(Right_HS); 
                    
                    index = 1:length(time);
                    Left_HS_Index = index(Left_HS);
                    index = 1:length(time);
                    Right_HS_Index = index(Right_HS);
                    
                    L_middle_25 = ceil(length(Left_HS_Index)*.25);                              % finds value for 25% of detected # of events
                    L_middle_50 = ceil(length(Left_HS_Index)*.75);                              % finds value for 50% of detected # of events

                    Left_HS_Index_mid = Left_HS_Index(L_middle_25:L_middle_50);
                    
                    
                    R_middle_25 = ceil(length(Right_HS_Index)*.25);                              % finds value for 25% of detected # of events
                    R_middle_50 = ceil(length(Right_HS_Index)*.75);                              % finds value for 50% of detected # of events

                    Right_HS_Index_mid = Right_HS_Index(R_middle_25:R_middle_50);
                    
                    for i=1:length(Left_HS_Index_mid)-1                                           % Creates a variable for how much time passed during each step based on HS to HS-1
                        Left_period(i) = time((Left_HS_Index_mid(i+1)-1))-time((Left_HS_Index_mid(i)));
                    end

                    period_mean_L = mean(Left_period);
                    
                    for i=1:length(Right_HS_Index_mid)-1                                           % Creates a variable for how much time passed during each step based on HS to HS-1
                        Right_period(i) = time((Right_HS_Index_mid(i+1)-1))-time((Right_HS_Index_mid(i)));
                    end

                    period_mean_R = mean(Right_period);
                    
                    for i=1:length(Left_HS_Index_mid)-1                                           % creates cell arrays for the times from start and end of each step
                        Time_bin_L{i} = time(Left_HS_Index_mid(i):(Left_HS_Index_mid(i+1)-1));
                    end
                                 
                    jLeftHip = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,3);
                    
                    for i=1:length(Right_HS_Index_mid)-1                                           % creates cell arrays for the times from start and end of each step
                        Time_bin_R{i} = time(Right_HS_Index_mid(i):(Right_HS_Index_mid(i+1)-1));
                    end
                                                         
                    jRightHip = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,3);
                    
                    for i=1:length(Left_HS_Index_mid)-1                                           % creates cell arrays for the Hip angle from start and end of each step
                        Left_Hipbin{i} = jLeftHip(Left_HS_Index_mid(i):(Left_HS_Index_mid(i+1)-1));
                    end

                    jLeftKnee = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,3);
                    
                    for i=1:length(Right_HS_Index_mid)-1                                           % creates cell arrays for the Hip angle from start and end of each step
                        Right_Hipbin{i} = jRightHip(Right_HS_Index_mid(i):(Right_HS_Index_mid(i+1)-1));
                    end

                    jRightKnee = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,3);
                    
                    for i=1:length(Left_HS_Index_mid)-1                                           % creates cell arrays for the Hip angle from start and end of each step
                        Left_Kneebin{i} = jLeftKnee(Left_HS_Index_mid(i):Left_HS_Index_mid(i+1)-1);
                    end

                    L_FRM = round(mean(cellfun(@length,Left_Hipbin)));             % finds the average number of frames for all steps
                    
                    for i=1:length(Right_HS_Index_mid)-1                                           % creates cell arrays for the Hip angle from start and end of each step
                        Right_Kneebin{i} = jRightKnee(Right_HS_Index_mid(i):Right_HS_Index_mid(i+1)-1);
                    end

                    R_FRM = round(mean(cellfun(@length,Right_Hipbin)));             % finds the average number of frames for all steps

                    for i =1:length(Time_bin_L)
                        x = Time_bin_L{i}';
                        T1 = x(1);
                        T2 = x(end);
                        T3 = T2 - T1;
                        xq = T1:(T3/(L_FRM-1)):T2;
                        v1 = Left_Hipbin{i}';
                        v2 = Left_Kneebin{i}';
                        
                        Left_Hip_norm(:,i) = interp1(x,v1,xq,'spline')';
                        Left_Knee_norm(:,i) = interp1(x,v2,xq,'spline')';

                    end
                    for i=1:L_FRM-1
   
                        L_HL(i,:) = Left_Hip_norm(i+1,:)-Left_Hip_norm(i,:);
                        L_KL(i,:) = Left_Knee_norm(i+1,:)-Left_Knee_norm(i,:);    
    
                    end
                    
                    L_L = sqrt(L_KL.^2+L_HL.^2);
                    L_cost = L_HL./L_L;   % cos(theta) = x(hip)/L
                    L_sint = L_KL./L_L;   %sin(theta)= y(knee)/L

                    for i=1:length(L_cost)
                        L_acost(i,:) = mean(L_cost(i,:));
                        L_asint(i,:) = mean(L_sint(i,:));
                    end
                    
                    L_A = sqrt(L_acost.^2+L_asint.^2);
                    
                    Left_ACC = sum(L_A)/L_FRM;
                    
                    app.LeftACCEditField.Value = Left_ACC;
                    
                    for i =1:length(Time_bin_R)
                        x = Time_bin_R{i}';
                        T1 = x(1);
                        T2 = x(end);
                        T3 = T2 - T1;
                        xq = T1:(T3/(R_FRM-1)):T2;
                        v1 = Right_Hipbin{i}';
                        v2 = Right_Kneebin{i}';
                        
                        Right_Hip_norm(:,i) = interp1(x,v1,xq,'spline')';
                        Right_Knee_norm(:,i) = interp1(x,v2,xq,'spline')';
                    
                    end
                    
                    for i=1:R_FRM-1
                       
                        R_HL(i,:) = Right_Hip_norm(i+1,:)-Right_Hip_norm(i,:);
                        R_KL(i,:) = Right_Knee_norm(i+1,:)-Right_Knee_norm(i,:);    
                        
                    end
                    
                    R_L = sqrt(R_KL.^2+R_HL.^2);
                    
                    R_cost = R_HL./R_L;   % cos(theta) = x(hip)/L
                    R_sint = R_KL./R_L;   %sin(theta)= y(knee)/L
                    
                    for i=1:length(R_cost)
                        R_acost(i,:) = mean(R_cost(i,:));
                        R_asint(i,:) = mean(R_sint(i,:));
                    end
                    
                    R_A = sqrt(R_acost.^2+R_asint.^2);
                    
                    Right_ACC = sum(R_A)/R_FRM;

                    app.RightACCEditField.Value = Right_ACC;
                    
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
            app.usersValue1 =[];
            
            files = (dir ('*.mvnx')); %list of mvnx files
            y = ({files.name})';
                        
            New_Data = app.UITable.Data(:,2);
            
            New_Data = cell2mat(New_Data);
            
            [app.usersValue1,col] = find(New_Data); % Finds the row that was selected and uses file for analysis 
            app.N = char(app.UITable.Data(app.usersValue1,1));


        end

        % Callback function
        function X_axisEditFieldValueChanged(app, event)
%%
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.JointDataNode = uitreenode(app.Tree);
            app.JointDataNode.Text = 'Joint Data';
            app.jL5S1Node = uitreenode(app.JointDataNode);
            app.jL5S1Node.Text = 'jL5S1';
            app.XNode = uitreenode(app.jL5S1Node);
            app.XNode.Text = 'X';
            app.YNode = uitreenode(app.jL5S1Node);
            app.YNode.Text = 'Y';
            app.ZNode = uitreenode(app.jL5S1Node);
            app.ZNode.Text = 'Z';
            app.jL4L3Node = uitreenode(app.JointDataNode);
            app.jL4L3Node.Text = 'jL4L3';
            app.XNode_2 = uitreenode(app.jL4L3Node);
            app.XNode_2.Text = 'X';
            app.YNode_2 = uitreenode(app.jL4L3Node);
            app.YNode_2.Text = 'Y';
            app.ZNode_2 = uitreenode(app.jL4L3Node);
            app.ZNode_2.Text = 'Z';
            app.jL1T12Node = uitreenode(app.JointDataNode);
            app.jL1T12Node.Text = 'jL1T12';
            app.XNode_22 = uitreenode(app.jL1T12Node);
            app.XNode_22.Text = 'X';
            app.YNode_21 = uitreenode(app.jL1T12Node);
            app.YNode_21.Text = 'Y';
            app.ZNode_21 = uitreenode(app.jL1T12Node);
            app.ZNode_21.Text = 'Z';
            app.jT9T8Node = uitreenode(app.JointDataNode);
            app.jT9T8Node.Text = 'jT9T8';
            app.XNode_3 = uitreenode(app.jT9T8Node);
            app.XNode_3.Text = 'X';
            app.YNode_3 = uitreenode(app.jT9T8Node);
            app.YNode_3.Text = 'Y';
            app.ZNode_3 = uitreenode(app.jT9T8Node);
            app.ZNode_3.Text = 'Z';
            app.jT1C7Node = uitreenode(app.JointDataNode);
            app.jT1C7Node.Text = 'jT1C7';
            app.XNode_5 = uitreenode(app.jT1C7Node);
            app.XNode_5.Text = 'X';
            app.YNode_4 = uitreenode(app.jT1C7Node);
            app.YNode_4.Text = 'Y';
            app.ZNode_4 = uitreenode(app.jT1C7Node);
            app.ZNode_4.Text = 'Z';
            app.jC1HeadNode = uitreenode(app.JointDataNode);
            app.jC1HeadNode.Text = 'jC1Head';
            app.XNode_23 = uitreenode(app.jC1HeadNode);
            app.XNode_23.Text = 'X';
            app.YNode_22 = uitreenode(app.jC1HeadNode);
            app.YNode_22.Text = 'Y';
            app.ZNode_22 = uitreenode(app.jC1HeadNode);
            app.ZNode_22.Text = 'Z';
            app.jRightT4ShoulderNode = uitreenode(app.JointDataNode);
            app.jRightT4ShoulderNode.Text = 'jRightT4Shoulder';
            app.XNode_6 = uitreenode(app.jRightT4ShoulderNode);
            app.XNode_6.Text = 'X';
            app.YNode_5 = uitreenode(app.jRightT4ShoulderNode);
            app.YNode_5.Text = 'Y';
            app.ZNode_5 = uitreenode(app.jRightT4ShoulderNode);
            app.ZNode_5.Text = 'Z';
            app.jRightShoulderNode = uitreenode(app.JointDataNode);
            app.jRightShoulderNode.Text = 'jRightShoulder';
            app.XNode_7 = uitreenode(app.jRightShoulderNode);
            app.XNode_7.Text = 'X';
            app.YNode_6 = uitreenode(app.jRightShoulderNode);
            app.YNode_6.Text = 'Y';
            app.ZNode_6 = uitreenode(app.jRightShoulderNode);
            app.ZNode_6.Text = 'Z';
            app.jRightElbowNode = uitreenode(app.JointDataNode);
            app.jRightElbowNode.Text = 'jRightElbow';
            app.XNode_8 = uitreenode(app.jRightElbowNode);
            app.XNode_8.Text = 'X';
            app.YNode_7 = uitreenode(app.jRightElbowNode);
            app.YNode_7.Text = 'Y';
            app.ZNode_7 = uitreenode(app.jRightElbowNode);
            app.ZNode_7.Text = 'Z';
            app.jRightWristNode = uitreenode(app.JointDataNode);
            app.jRightWristNode.Text = 'jRightWrist';
            app.XNode_9 = uitreenode(app.jRightWristNode);
            app.XNode_9.Text = 'X';
            app.YNode_8 = uitreenode(app.jRightWristNode);
            app.YNode_8.Text = 'Y';
            app.ZNode_8 = uitreenode(app.jRightWristNode);
            app.ZNode_8.Text = 'Z';
            app.jLeftT4ShoulderNode = uitreenode(app.JointDataNode);
            app.jLeftT4ShoulderNode.Text = 'jLeftT4Shoulder';
            app.XNode_10 = uitreenode(app.jLeftT4ShoulderNode);
            app.XNode_10.Text = 'X';
            app.YNode_9 = uitreenode(app.jLeftT4ShoulderNode);
            app.YNode_9.Text = 'Y';
            app.ZNode_9 = uitreenode(app.jLeftT4ShoulderNode);
            app.ZNode_9.Text = 'Z';
            app.jLeftShoulderNode = uitreenode(app.JointDataNode);
            app.jLeftShoulderNode.Text = 'jLeftShoulder';
            app.XNode_11 = uitreenode(app.jLeftShoulderNode);
            app.XNode_11.Text = 'X';
            app.YNode_10 = uitreenode(app.jLeftShoulderNode);
            app.YNode_10.Text = 'Y';
            app.ZNode_10 = uitreenode(app.jLeftShoulderNode);
            app.ZNode_10.Text = 'Z';
            app.jLeftElbowNode = uitreenode(app.JointDataNode);
            app.jLeftElbowNode.Text = 'jLeftElbow';
            app.XNode_12 = uitreenode(app.jLeftElbowNode);
            app.XNode_12.Text = 'X';
            app.YNode_11 = uitreenode(app.jLeftElbowNode);
            app.YNode_11.Text = 'Y';
            app.ZNode_11 = uitreenode(app.jLeftElbowNode);
            app.ZNode_11.Text = 'Z';
            app.jLeftWristNode = uitreenode(app.JointDataNode);
            app.jLeftWristNode.Text = 'jLeftWrist';
            app.XNode_13 = uitreenode(app.jLeftWristNode);
            app.XNode_13.Text = 'X';
            app.YNode_12 = uitreenode(app.jLeftWristNode);
            app.YNode_12.Text = 'Y';
            app.ZNode_12 = uitreenode(app.jLeftWristNode);
            app.ZNode_12.Text = 'Z';
            app.jRightHipNode = uitreenode(app.JointDataNode);
            app.jRightHipNode.Text = 'jRightHip';
            app.XNode_14 = uitreenode(app.jRightHipNode);
            app.XNode_14.Text = 'X';
            app.YNode_13 = uitreenode(app.jRightHipNode);
            app.YNode_13.Text = 'Y';
            app.ZNode_13 = uitreenode(app.jRightHipNode);
            app.ZNode_13.Text = 'Z';
            app.jRightKneeNode = uitreenode(app.JointDataNode);
            app.jRightKneeNode.Text = 'jRightKnee';
            app.XNode_15 = uitreenode(app.jRightKneeNode);
            app.XNode_15.Text = 'X';
            app.YNode_14 = uitreenode(app.jRightKneeNode);
            app.YNode_14.Text = 'Y';
            app.ZNode_14 = uitreenode(app.jRightKneeNode);
            app.ZNode_14.Text = 'Z';
            app.jRightAnkleNode = uitreenode(app.JointDataNode);
            app.jRightAnkleNode.Text = 'jRightAnkle';
            app.XNode_16 = uitreenode(app.jRightAnkleNode);
            app.XNode_16.Text = 'X';
            app.YNode_15 = uitreenode(app.jRightAnkleNode);
            app.YNode_15.Text = 'Y';
            app.ZNode_15 = uitreenode(app.jRightAnkleNode);
            app.ZNode_15.Text = 'Z';
            app.jRightBallFootNode = uitreenode(app.JointDataNode);
            app.jRightBallFootNode.Text = 'jRightBallFoot';
            app.XNode_17 = uitreenode(app.jRightBallFootNode);
            app.XNode_17.Text = 'X';
            app.YNode_16 = uitreenode(app.jRightBallFootNode);
            app.YNode_16.Text = 'Y';
            app.ZNode_16 = uitreenode(app.jRightBallFootNode);
            app.ZNode_16.Text = 'Z';
            app.jLeftHipNode = uitreenode(app.JointDataNode);
            app.jLeftHipNode.Text = 'jLeftHip';
            app.XNode_18 = uitreenode(app.jLeftHipNode);
            app.XNode_18.Text = 'X';
            app.YNode_17 = uitreenode(app.jLeftHipNode);
            app.YNode_17.Text = 'Y';
            app.ZNode_17 = uitreenode(app.jLeftHipNode);
            app.ZNode_17.Text = 'Z';
            app.jLeftKneeNode = uitreenode(app.JointDataNode);
            app.jLeftKneeNode.Text = 'jLeftKnee';
            app.XNode_19 = uitreenode(app.jLeftKneeNode);
            app.XNode_19.Text = 'X';
            app.YNode_18 = uitreenode(app.jLeftKneeNode);
            app.YNode_18.Text = 'Y';
            app.ZNode_18 = uitreenode(app.jLeftKneeNode);
            app.ZNode_18.Text = 'Z';
            app.jLeftAnkleNode = uitreenode(app.JointDataNode);
            app.jLeftAnkleNode.Text = 'jLeftAnkle';
            app.XNode_20 = uitreenode(app.jLeftAnkleNode);
            app.XNode_20.Text = 'X';
            app.YNode_19 = uitreenode(app.jLeftAnkleNode);
            app.YNode_19.Text = 'Y';
            app.ZNode_19 = uitreenode(app.jLeftAnkleNode);
            app.ZNode_19.Text = 'Z';
            app.jLeftBallFootNode = uitreenode(app.JointDataNode);
            app.jLeftBallFootNode.Text = 'jLeftBallFoot';
            app.XNode_21 = uitreenode(app.jLeftBallFootNode);
            app.XNode_21.Text = 'X';
            app.YNode_20 = uitreenode(app.jLeftBallFootNode);
            app.YNode_20.Text = 'Y';
            app.ZNode_20 = uitreenode(app.jLeftBallFootNode);
            app.ZNode_20.Text = 'Z';
            app.RelativeMotionPlotsNode = uitreenode(app.Tree);
            app.RelativeMotionPlotsNode.Text = 'Relative Motion Plots';
            app.R_HipXvsKneeYNode = uitreenode(app.RelativeMotionPlotsNode);
            app.R_HipXvsKneeYNode.Text = 'R_Hip [X] vs. Knee [Y]';
            app.L_HipXvsKneeYNode = uitreenode(app.RelativeMotionPlotsNode);
            app.L_HipXvsKneeYNode.Text = 'L_Hip [X] vs. Knee [Y]';
            app.R_HipKneeAnkleNode = uitreenode(app.RelativeMotionPlotsNode);
            app.R_HipKneeAnkleNode.Text = 'R_Hip, Knee, Ankle';
            app.XNode_24 = uitreenode(app.R_HipKneeAnkleNode);
            app.XNode_24.Text = 'X';
            app.YNode_23 = uitreenode(app.R_HipKneeAnkleNode);
            app.YNode_23.Text = 'Y';
            app.ZNode_23 = uitreenode(app.R_HipKneeAnkleNode);
            app.ZNode_23.Text = 'Z';
            app.L_HipKneeAnkleNode = uitreenode(app.RelativeMotionPlotsNode);
            app.L_HipKneeAnkleNode.Text = 'L_Hip, Knee, Ankle';
            app.XNode_25 = uitreenode(app.L_HipKneeAnkleNode);
            app.XNode_25.Text = 'X';
            app.YNode_24 = uitreenode(app.L_HipKneeAnkleNode);
            app.YNode_24.Text = 'Y';
            app.ZNode_24 = uitreenode(app.L_HipKneeAnkleNode);
            app.ZNode_24.Text = 'Z';
            app.Tree2 = uitree(app.UIFigure);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.WalkingNode_2 = uitreenode(app.Tree2);
            app.WalkingNode_2.Text = 'Walking';
            app.HS_TO_RNode = uitreenode(app.WalkingNode_2);
            app.HS_TO_RNode.Text = 'HS_TO_R';
            app.HS_TO_LNode = uitreenode(app.WalkingNode_2);
            app.HS_TO_LNode.Text = 'HS_TO_L';
            app.ACCNode_2 = uitreenode(app.WalkingNode_2);
            app.ACCNode_2.Text = 'ACC';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Participant ID#'; 'Analyze'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable2.RowName = {};
            app.RightACCEditFieldLabel = uilabel(app.UIFigure);
            app.RightACCEditFieldLabel.Text = 'Right ACC';
            app.RightACCEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftACCEditFieldLabel = uilabel(app.UIFigure);
            app.LeftACCEditFieldLabel.Text = 'Left ACC';
            app.LeftACCEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xsens_APP_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end