classdef Xsens_Validation_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Export_Data             matlab.ui.container.Menu
        UITable2                matlab.ui.control.Table
        LeftACCEditField        matlab.ui.control.NumericEditField
        LeftACCEditFieldLabel   matlab.ui.control.Label
        RightACCEditField       matlab.ui.control.NumericEditField
        RightACCEditFieldLabel  matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        Tree                    matlab.ui.container.CheckBoxTree
        J_Right_HipNode         matlab.ui.container.TreeNode
        XNode                   matlab.ui.container.TreeNode
        YNode                   matlab.ui.container.TreeNode
        ZNode                   matlab.ui.container.TreeNode
        J_Left_HipNode          matlab.ui.container.TreeNode
        XNode_2                 matlab.ui.container.TreeNode
        YNode_2                 matlab.ui.container.TreeNode
        ZNode_2                 matlab.ui.container.TreeNode
        J_Right_KneeNode        matlab.ui.container.TreeNode
        XNode_3                 matlab.ui.container.TreeNode
        YNode_3                 matlab.ui.container.TreeNode
        ZNode_3                 matlab.ui.container.TreeNode
        J_Left_KneeNode         matlab.ui.container.TreeNode
        XNode_4                 matlab.ui.container.TreeNode
        YNode_4                 matlab.ui.container.TreeNode
        ZNode_4                 matlab.ui.container.TreeNode
        J_Right_AnkleNode       matlab.ui.container.TreeNode
        XNode_5                 matlab.ui.container.TreeNode
        YNode_5                 matlab.ui.container.TreeNode
        ZNode_5                 matlab.ui.container.TreeNode
        J_Left_AnkleNode        matlab.ui.container.TreeNode
        XNode_6                 matlab.ui.container.TreeNode
        YNode_6                 matlab.ui.container.TreeNode
        ZNode_6                 matlab.ui.container.TreeNode
        J_Right_ToeNode         matlab.ui.container.TreeNode
        XNode_7                 matlab.ui.container.TreeNode
        YNode_7                 matlab.ui.container.TreeNode
        ZNode_7                 matlab.ui.container.TreeNode
        J_Left_ToeNode          matlab.ui.container.TreeNode
        XNode_8                 matlab.ui.container.TreeNode
        YNode_8                 matlab.ui.container.TreeNode
        ZNode_8                 matlab.ui.container.TreeNode
        UIAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        tree; files;str;JData;JData_x;JData_y;JData_z; prompt;fileList;TData;usersValue1;N;y; % Description
    end
    methods (Access = private)
        
        function LoadData(app)
          
            app.prompt = questdlg('How do you want to import your data?',...
                'GUI','Import Folder','Import File','Import File');
            
            switch app.prompt
                
                case 'Import Folder'
                
                %uigetdir('C:\Users\Jessica S. Gilliam\Documents\MATLAB\ShepherdCenter'); % Set directory
                app.files = dir('*.mvnx');
                app.fileList = dir('*.*');
                
                files = (dir ('*.mvnx')); %list of mvnx files
                app.y = ({files.name})';
                
                    for i = 1:length(app.files)
                        app.tree.data(i) = load_mvnx(app.files(i).name);
                    end
                    
                case 'Import File'
                
                    %uigetdir('C:\Users\Jessica S. Gilliam\Documents\MATLAB\ShepherdCenter'); % Set directory
                    app.files = uigetfile('*.mvnx');
                    app.y = ({app.files})';
                    app.fileList = dir('*.*');
                    app.tree.data = load_mvnx(app.files);
                
            end
            
                     
        end
        
        
        function ExportData(app)
           
%             if app.prompt 
%                 
%                 for i = 1:length(app.files)
%                     for j = 1:22
%                         for s = 1:23
%                             Table_S = app.tree.data(i).segmentData(s).orientation;
%                         end
%                         Table_J = app.tree.data(i).jointData(j).jointAngle;
%                     end
%                                   
%                        
%                     y_1 = num2str(i);
%                     
%                     filename = 'Participant_JointData.xls';
%                     writematrix(Table_J, filename,'Sheet',y_1);
%                 end
%             else
%                 
%                 filename = 'Participant_JointData.xls';
%                 writematrix(app.tree.data,filename,'Sheet',y_1);
%             end
        end
        
%         function Analyze(app)
            
%             promptn = input('Select Analysis Type:[JointAngle(1)] ');
%                     
%                     if promptn
%                         
%                         app.str = input('What joint are you looking at? Write specific Xsens joint name: ','s');
%                                                 
%                         C = {'jL5S1','jL4L3','jL1T12','jT9T8','jT1C7','jC1Head','jRightT4Shoulder','jRightShoulder',...
%                         'jRightElbow','jRightWrist','jLeftT4Shoulder','jLeftShoulder','jLeftElbow','jLeftWrist',...
%                         'jRightHip','jRightKnee','jRightAnkle','jRightBallFoot','jLeftHip','jLeftKnee','jLeftAnkle',...
%                         'jLeftBallFoot'};
%                     
%                         idx = strcmpi(app.str, C);
%                         [r,c] = find(idx);
%                         
%                         prompt2 = input('What Axis? X(2), Y(1), Z(0): ');
%                                                 
%                         prompt3 = input('What Participant are you analyzing? [ID num]: ');
%                         set = [1,5,6]; % this can be changed here
%                         [a,b] = find(set == prompt3);
%                         app.JData = app.tree.data(b).jointData(c).jointAngle(:,prompt2);
%                                               
%                         
%                     end
            
%         end
         
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             LoadData(app)
%             app.Tree.Multiselect = 'on';
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%5
% Updating Table View
            %files = (dir ('*.mvnx')); %list of mvnx files
            %y = ({files.name})';
            checkbox = (repelem({false},length(app.y)))';
            app.TData = [app.y checkbox];
            
            app.UITable.Data = app.TData;

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
                        
            switch Nodes
%                 case app.jL5S1Node
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(1).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(1).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(1).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jL4L3Node
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(2).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(2).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(2).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jL1T12Node
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(3).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(3).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(3).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jT9T8Node
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(4).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(4).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(4).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jT1C7Node
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(5).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(5).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(5).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                         %end
%                 case app.jC1HeadNode
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(6).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(6).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(6).jointAngle(:,3);
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jRightT4ShoulderNode
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(7).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(7).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(7).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jRightShoulderNode
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(8).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(8).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(8).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jRightElbowNode
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(9).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(9).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(9).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jRightWristNode
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(10).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(10).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(10).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jLeftT4ShoulderNode
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(11).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(11).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(11).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jLeftShoulderNode
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(12).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(12).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(12).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jLeftElbowNode
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(13).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(13).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(13).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
%                 case app.jLeftWristNode
%                     %if usersValue2
%                         app.JData_x = app.tree.data(app.usersValue1).jointData(14).jointAngle(:,1);
%                         %if usersValue3
%                             app.JData_y = app.tree.data(app.usersValue1).jointData(14).jointAngle(:,2);
%                             %if usersValue4
%                                 app.JData_z = app.tree.data(app.usersValue1).jointData(14).jointAngle(:,3);
%                                 
%                                 app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
%                             %end
%                         %end
%                     %end
                case app.J_Right_HipNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.J_Right_KneeNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.J_Right_AnkleNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.J_Right_ToeNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(18).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(18).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(18).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                case app.J_Left_HipNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
            
                case app.J_Left_KneeNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                    
                case app.J_Left_AnkleNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
                    %end
                     
                case app.J_Left_ToeNode
                    %if usersValue2
                        app.JData_x = app.tree.data(app.usersValue1).jointData(22).jointAngle(:,1);
                        %if usersValue3
                            app.JData_y = app.tree.data(app.usersValue1).jointData(22).jointAngle(:,2);
                            %if usersValue4
                                app.JData_z = app.tree.data(app.usersValue1).jointData(22).jointAngle(:,3);
                                
                                app.UITable2.Data = [app.JData_x, app.JData_y, app.JData_z];
                            %end
                        %end
            end
            
  %%%%%%%%%%%%%%%%%% Relative Motion Plots Section
    Nodes = event.SelectedNodes;
            switch Nodes
                case app.R_HipXvsKneeYNode
                   app.JData_x = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,3); %Hip
                   app.JData_y = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,3); %Knee
                   plot(app.UIAxes,app.JData_x,app.JData_y)
                   app.UITable2.Data = [app.JData_x, app.JData_y];
                   
                case app.L_HipXvsKneeYNode
                   app.JData_x = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,3);
                   app.JData_y = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,3);
                   plot(app.UIAxes,app.JData_x,app.JData_y)
                   app.UITable2.Data = [app.JData_x, app.JData_y];
            end

    Nodes = event.SelectedNodes;
            switch Nodes
                %%% Right
                case app.XNode_24
                    JData_x1 = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,1); % Hip
                    JData_x2 = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,1); % Knee
                    JData_x3 = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,1); % Ankle
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_x1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_x2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_x3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                    app.UITable2.Data = [JData_x1, JData_x2, JData_x3];
                    
                case app.YNode_23
                    JData_y1 = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,2);
                    JData_y2 = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,2);
                    JData_y3 = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,2);
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_y1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_y2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_y3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                    app.UITable2.Data = [JData_y1, JData_y2, JData_y3];
                    
                case app.ZNode_23
                    JData_z1 = app.tree.data(app.usersValue1).jointData(15).jointAngle(:,3);
                    JData_z2 = app.tree.data(app.usersValue1).jointData(16).jointAngle(:,3);
                    JData_z3 = app.tree.data(app.usersValue1).jointData(17).jointAngle(:,3);
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_z1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_z2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_z3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                    app.UITable2.Data = [JData_z1, JData_z2, JData_z3];
                    
                %%% Left
                case app.XNode_25
                    JData_x1 = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,1);
                    JData_x2 = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,1);
                    JData_x3 = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,1);
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_x1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_x2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_x3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                     app.UITable2.Data = [JData_x1, JData_x2, JData_x3];
                    
                case app.YNode_24
                    JData_y1 = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,2);
                    JData_y2 = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,2);
                    JData_y3 = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,2);
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_y1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_y2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_y3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                    app.UITable2.Data = [JData_y1, JData_y2, JData_y3];
                    
                case app.ZNode_24
                    JData_z1 = app.tree.data(app.usersValue1).jointData(19).jointAngle(:,3);
                    JData_z2 = app.tree.data(app.usersValue1).jointData(20).jointAngle(:,3);
                    JData_z3 = app.tree.data(app.usersValue1).jointData(21).jointAngle(:,3);
                    
                    
                    time = str2double({app.tree.data(app.usersValue1).frame.time});
                    time = (time)/1000;
                    
                    plot(app.UIAxes,time,JData_z1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_z2)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,time,JData_z3)
                    legend(app.UIAxes,'Hip','Knee','Ankle');
                    
                    app.UITable2.Data = [JData_z1, JData_z2, JData_z3];
                    
            end
% From selected joint Data - shows x,y,z values and graphical display of x,y,z

%                     T = table(app.JData_x, app.JData_y, app.JData_z);
%                     T.Properties.VariableNames = {'X','Y','Z'};
%                     fig = uifigure('Name',app.N);
%                     fig.Position = [20 20 400 600];
                    
%                     ax = uiaxes(fig);
%                     ax2 = uiaxes(fig);
%                     ax3 = uiaxes(fig);
%                     ax.Position = [400 10 500 200];
%                     ax2.Position = [400 200 500 200];
%                     ax3.Position = [400 400 500 200];
%                     ax.YLabel.String = '[X]';
%                     ax2.YLabel.String = '[Y]';
%                     ax3.YLabel.String = '[Z]';

                reset(app.UIAxes);
                time = str2double({app.tree.data(app.usersValue1).frame.time});
                time = (time)/1000; 


%                 reset(app.UIAxes_2);
%                 reset(app.UIAxes_3);
                
            Nodes = event.SelectedNodes;
            reset(app.UIAxes); 
            switch Nodes
               %%%% Drop Down %%%% 
                case app.XNode
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_2
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_2
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_2
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_22
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_21
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_21
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_3
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_3
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_3
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_5
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_4
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_4
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_23
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_22
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_22
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_6
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_5
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_5
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_7
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_6
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_6
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_8
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_7
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_7
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_9
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_8
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_8
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_10
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_9
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_9
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_11
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_10
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_10
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_12
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_11
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_11
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_13
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_12
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_12
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_14
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_13
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_13
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_15
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_14
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_14
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_16
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_15
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_15
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_17
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_16
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_16
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_18
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_17
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_17
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_19
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_18
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_18
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_20
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_19
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_19
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
                %%%% Drop Down %%%%    
                case app.XNode_21
                    y1 = app.JData_x;
                    plot(app.UIAxes,time,y1)
                case app.YNode_20
                    y2 = app.JData_y;
                    plot(app.UIAxes,time,y2)
                case app.ZNode_20
                    y3 = app.JData_z;
                    plot(app.UIAxes,time,y3)
            end
                    
                  
%                     ylabel(app.UIAxes, '[X]');
%                     ylabel(app.UIAxes_2, '[Y]');
%                     ylabel(app.UIAxes_3, '[Z]');
                
                    
%                     uit = uitable(fig,'Data',T);
%                     uit.Position = [10 10 400 550];
%                     uit.RowName = 'numbered';
%                     fig.AutoResizeChildren = 'on';
                    

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Export_Data = uimenu(app.UIFigure);
            app.Export_Data.Text = 'Export_Data';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.J_Right_HipNode = uitreenode(app.Tree);
            app.J_Right_HipNode.Text = 'J_Right_Hip';
            app.XNode = uitreenode(app.J_Right_HipNode);
            app.XNode.Text = 'X';
            app.YNode = uitreenode(app.J_Right_HipNode);
            app.YNode.Text = 'Y';
            app.ZNode = uitreenode(app.J_Right_HipNode);
            app.ZNode.Text = 'Z';
            app.J_Left_HipNode = uitreenode(app.Tree);
            app.J_Left_HipNode.Text = 'J_Left_Hip';
            app.XNode_2 = uitreenode(app.J_Left_HipNode);
            app.XNode_2.Text = 'X';
            app.YNode_2 = uitreenode(app.J_Left_HipNode);
            app.YNode_2.Text = 'Y';
            app.ZNode_2 = uitreenode(app.J_Left_HipNode);
            app.ZNode_2.Text = 'Z';
            app.J_Right_KneeNode = uitreenode(app.Tree);
            app.J_Right_KneeNode.Text = 'J_Right_Knee';
            app.XNode_3 = uitreenode(app.J_Right_KneeNode);
            app.XNode_3.Text = 'X';
            app.YNode_3 = uitreenode(app.J_Right_KneeNode);
            app.YNode_3.Text = 'Y';
            app.ZNode_3 = uitreenode(app.J_Right_KneeNode);
            app.ZNode_3.Text = 'Z';
            app.J_Left_KneeNode = uitreenode(app.Tree);
            app.J_Left_KneeNode.Text = 'J_Left_Knee';
            app.XNode_4 = uitreenode(app.J_Left_KneeNode);
            app.XNode_4.Text = 'X';
            app.YNode_4 = uitreenode(app.J_Left_KneeNode);
            app.YNode_4.Text = 'Y';
            app.ZNode_4 = uitreenode(app.J_Left_KneeNode);
            app.ZNode_4.Text = 'Z';
            app.J_Right_AnkleNode = uitreenode(app.Tree);
            app.J_Right_AnkleNode.Text = 'J_Right_Ankle';
            app.XNode_5 = uitreenode(app.J_Right_AnkleNode);
            app.XNode_5.Text = 'X';
            app.YNode_5 = uitreenode(app.J_Right_AnkleNode);
            app.YNode_5.Text = 'Y';
            app.ZNode_5 = uitreenode(app.J_Right_AnkleNode);
            app.ZNode_5.Text = 'Z';
            app.J_Left_AnkleNode = uitreenode(app.Tree);
            app.J_Left_AnkleNode.Text = 'J_Left_Ankle';
            app.XNode_6 = uitreenode(app.J_Left_AnkleNode);
            app.XNode_6.Text = 'X';
            app.YNode_6 = uitreenode(app.J_Left_AnkleNode);
            app.YNode_6.Text = 'Y';
            app.ZNode_6 = uitreenode(app.J_Left_AnkleNode);
            app.ZNode_6.Text = 'Z';
            app.J_Right_ToeNode = uitreenode(app.Tree);
            app.J_Right_ToeNode.Text = 'J_Right_Toe';
            app.XNode_7 = uitreenode(app.J_Right_ToeNode);
            app.XNode_7.Text = 'X';
            app.YNode_7 = uitreenode(app.J_Right_ToeNode);
            app.YNode_7.Text = 'Y';
            app.ZNode_7 = uitreenode(app.J_Right_ToeNode);
            app.ZNode_7.Text = 'Z';
            app.J_Left_ToeNode = uitreenode(app.Tree);
            app.J_Left_ToeNode.Text = 'J_Left_Toe';
            app.XNode_8 = uitreenode(app.J_Left_ToeNode);
            app.XNode_8.Text = 'X';
            app.YNode_8 = uitreenode(app.J_Left_ToeNode);
            app.YNode_8.Text = 'Y';
            app.ZNode_8 = uitreenode(app.J_Left_ToeNode);
            app.ZNode_8.Text = 'Z';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Participant ID#'; 'Analyze'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.RightACCEditFieldLabel = uilabel(app.UIFigure);
            app.RightACCEditFieldLabel.Text = 'Right ACC';
            app.RightACCEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftACCEditFieldLabel = uilabel(app.UIFigure);
            app.LeftACCEditFieldLabel.Text = 'Left ACC';
            app.LeftACCEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xsens_Validation_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end